classdef KeysightCoupling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MAXNMEditField              matlab.ui.control.NumericEditField
        MAXNMEditFieldLabel         matlab.ui.control.Label
        MINNMEditField              matlab.ui.control.NumericEditField
        MINNMEditFieldLabel         matlab.ui.control.Label
        STEPPMKnob                  matlab.ui.control.DiscreteKnob
        STEPPMKnobLabel             matlab.ui.control.Label
        LIMITSlider                 matlab.ui.control.RangeSlider
        LIMITSliderLabel            matlab.ui.control.Label
        FullShowCheckBox            matlab.ui.control.CheckBox
        Label_4                     matlab.ui.control.Label
        OPENMONITORButton           matlab.ui.control.Button
        RESETButton                 matlab.ui.control.StateButton
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        ParamEditField              matlab.ui.control.NumericEditField
        ParamEditFieldLabel         matlab.ui.control.Label
        QShowButton                 matlab.ui.control.Button
        INVERTCheckBox              matlab.ui.control.CheckBox
        ADDPOWERSTAMPCheckBox       matlab.ui.control.CheckBox
        ADDTIMESTAMPCheckBox        matlab.ui.control.CheckBox
        QANALYSISCheckBox           matlab.ui.control.CheckBox
        SWEEPPICTURECheckBox        matlab.ui.control.CheckBox
        RAWLOSSLAMBDACheckBox       matlab.ui.control.CheckBox
        READYLamp                   matlab.ui.control.Lamp
        READYLampLabel              matlab.ui.control.Label
        SWEEPButton                 matlab.ui.control.Button
        WAVELENGTHNMEditField       matlab.ui.control.NumericEditField
        WAVELENGTHNMEditFieldLabel  matlab.ui.control.Label
        NAMEEditField               matlab.ui.control.EditField
        NAMEEditFieldLabel          matlab.ui.control.Label
        SAVEButton                  matlab.ui.control.Button
        POWERDBMKnob                matlab.ui.control.DiscreteKnob
        POWERDBMLabel               matlab.ui.control.Label
        KEYSIGHTCOUPLINGLabel       matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        keysightLaser
        input_power 
        lambda
        power
    end
    
    properties (Access = public)
        NMrange
    end
    
    properties (Access = public)
        mypath
    end
    
    methods (Access = public)
        
        function updateAll(app)
            app.keysightLaser.setWavelength(app.WAVELENGTHNMEditField.Value);
            app.keysightLaser.setOpticalPower(str2double(app.POWERDBMKnob.Value));
            app.input_power = str2double(app.POWERDBMKnob.Value);
        end


        function ShowSweep(app)
            power_ = app.power*1000; lambda_ = app.lambda*1e9;
            lossdb=real(10*log10(power_))-double(app.input_power);
            plot(app.UIAxes,lambda_,lossdb,'LineWidth',1);
            xlabel(app.UIAxes,'Wavelength [nm]'); ylabel(app.UIAxes,'Loss [dB]');
            set(app.UIAxes,'fontname','david');
            set(app.UIAxes,'linewidth',1.2); app.UIAxes.FontSize = 19;
            xlim(app.UIAxes,[min(lambda_) max(lambda_)]);
        end

        function ShowQ(app)
            power_ = app.power*1000; lambda_ = app.lambda*1e9;
            lambda_ = linspace(min(lambda_),max(lambda_),length(lambda_))';
            if app.INVERTCheckBox.Value
                power_=1-power_;
                B = smoothdata(power_,"movmean",20000);
                power_=power_-B;
            end
            param = app.ParamEditField.Value;
            try
                [pks,locs,w,p] = findpeaks(power_,lambda_,'MinPeakProminence',param,'WidthReference','halfheight','Annotate','extents');
                if app.FullShowCheckBox.Value
                    findpeaks(power_,lambda_,'MinPeakProminence',param,'WidthReference','halfheight','Annotate','extents');
                    text(locs+.1,pks,num2str(round(locs./w)),"FontSize",15);
                    text(locs+.1,pks-.005,num2str(locs),"FontSize",15,'Color','r')
                end
            catch ME
                warning(ME.message);
                warning('could not find peaks');
                cla(app.UIAxes_2);
                return;
            end
            if isempty(locs)
                warning('could not find peaks');
                cla(app.UIAxes_2);
                return;
            end
            Q=locs./w;

            [~,i]=max(Q); locs(i)
            [~,ii]=min(abs(lambda_-locs(i)));
            lossdb=real(10*log10(power_)-double(app.input_power));

            fprintf('Max Q is %.fK at %.4fnm and %.2fdb\n',max(Q)/1000,locs(i),lossdb(ii));
            try
                fprintf('extinction is %.2fdb\n',max(lossdb(ii-10000:ii+10000))-lossdb(ii));
            catch
                fprintf('extinction is %.2fdb\n',max(lossdb)-lossdb(ii));
            end

            scatter(app.UIAxes_2,locs,Q/1e3,"filled");
            ylabel(app.UIAxes_2,'Q [thousands]'); xlabel(app.UIAxes_2,'Wavelength [nm]');
            set(app.UIAxes_2,'fontname','david');
            set(app.UIAxes_2,'linewidth',1.2); app.UIAxes_2.FontSize = 19;
            xlim(app.UIAxes,[min(lambda_) max(lambda_)]);
        end

        function SAVE(app)
            name = app.NAMEEditField.Value;
            datestamp = string(datetime);
            datestamp = strrep(datestamp,':','-');
            
            app.mypath = uigetdir(app.mypath);
            finalname = convertStringsToChars(append(app.mypath,'\',name,'_',datestamp,'_KEYSIGHT.mat'));
            loss=app.power;
            lda=app.lambda;
            input_power=app.input_power;
            save(finalname,'loss','lda','input_power');

            if app.SWEEPPICTURECheckBox.Value
                finalname = convertStringsToChars(append(app.mypath,'\',name,'_',datestamp,'_sweep-KEYSIGHT.png'));
                exportgraphics(app.UIAxes,finalname);
            end

            if app.QANALYSISCheckBox.Value
                finalname = convertStringsToChars(append(app.mypath,'\',name,'_',datestamp,'_Q-KEYSIGHT.png'));
                exportgraphics(app.UIAxes_2,finalname);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %addpath(genpath(pwd));
            try
            app.keysightLaser = KEYSIGHT();
            catch m
                app.delete;
                error('user cancelled');
            end
            app.keysightLaser.resetlaser;
            app.keysightLaser = KEYSIGHT();
            app.keysightLaser.setprints(1);
            try
                app.keysightLaser.startLaser;
            catch
            end
            app.updateAll;
            app.READYLamp.Color=[0 1 0];
            app.mypath = '.';
            linkaxes([app.UIAxes app.UIAxes_2],'x')
        end

        % Value changed function: WAVELENGTHNMEditField
        function WAVELENGTHNMEditFieldValueChanged(app, event)
            app.updateAll;
        end

        % Value changed function: POWERDBMKnob
        function POWERDBMKnobValueChanged(app, event)
            app.updateAll;
        end

        % Button pushed function: SWEEPButton
        function SWEEPButtonPushed(app, event)
            % sweep limited to 10dbm over the full range
            app.READYLamp.Color=[0 0 1];
            if str2double(app.POWERDBMKnob.Value) > 10
                app.POWERDBMKnob.Value='10';
                app.updateAll;
            end
            
            step = str2double(app.STEPPMKnob.Value)*1e-3;
            WLs = app.LIMITSlider.Value;
            
            speed = step/100e-6;

            try
                app.keysightLaser.SweepSETUP(WLs(1),WLs(2),step,speed);
                [app.lambda,app.power] = app.keysightLaser.SweepGO;
            catch ME
                warning(ME.message);
                warning('Error while swipping > reseting > retry please')
                app.RESETButtonValueChanged;
                return;
            end

            app.ShowSweep;
            app.updateAll;
            app.ShowQ;
            app.READYLamp.Color=[0 1 0];
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.READYLamp.Color=[0 0 1];
            app.SAVE;
            app.READYLamp.Color=[0 1 0];
        end

        % Value changed function: RESETButton
        function RESETButtonValueChanged(app, event)
            app.READYLamp.Color=[1 0 0];
            app.keysightLaser.resetlaser;
            app.startupFcn;
        end

        % Button pushed function: QShowButton
        function QShowButtonPushed(app, event)
            app.ShowQ;
        end

        % Button pushed function: OPENMONITORButton
        function OPENMONITORButtonPushed(app, event)
            try
                monitorG(app.keysightLaser);
            catch
                app.keysightLaser.flushh;
                app.keysightLaser.setprints(1);
                pause(1);
            end
        end

        % Value changed function: ParamEditField
        function ParamEditFieldValueChanged(app, event)
            app.ShowQ;
        end

        % Value changed function: STEPPMKnob
        function STEPPMKnobValueChanged(app, event)
            value = str2double(app.STEPPMKnob.Value);
            app.NMrange = 100*value;

            ret = app.LIMITSlider.Value;
            a=ret(1); b=ret(2);
            if (b-a)>app.NMrange
                c=(b+a)/2;
                d=app.NMrange/2;
                ret(1)=c-d; ret(2)=c+d;
                app.LIMITSlider.Value=ret;
                app.MAXNMEditField.Value=ret(2);
                app.MINNMEditField.Value=ret(1);
            end
        end

        % Value changed function: LIMITSlider
        function LIMITSliderValueChanged(app, event)
            whichone = abs(event.Value-event.PreviousValue)>0;
            ret = app.LIMITSlider.Value;

            a=ret(1); b=ret(2);
            if (b-a)>app.NMrange
                if whichone==[0 1]
                    ret(1)=max(1500,b-app.NMrange);
                else
                    ret(2)=min(1600,a+app.NMrange);
                end
                app.LIMITSlider.Value=ret;
            end
            app.MAXNMEditField.Value=ret(2);
            app.MINNMEditField.Value=ret(1);
        end

        % Value changed function: MINNMEditField
        function MINNMEditFieldValueChanged(app, event)
            value = app.MINNMEditField.Value;
            ret = app.LIMITSlider.Value;
            ret(1)=value;

            a=ret(1); b=ret(2);
            if a>b
                b=min(1600,a+app.NMrange);
            end
            if (b-a)>app.NMrange
                b=min(1600,a+app.NMrange);
            end
            ret = [a,b];
            app.LIMITSlider.Value=ret;
            app.MAXNMEditField.Value=ret(2);
        end

        % Value changed function: MAXNMEditField
        function MAXNMEditFieldValueChanged(app, event)
            value = app.MAXNMEditField.Value;
            ret = app.LIMITSlider.Value;
            ret(2)=value;

            a=ret(1); b=ret(2);
            if a>b
                a=max(1500,b-app.NMrange);
            end
            if (b-a)>app.NMrange
                a=max(1500,b-app.NMrange);
            end
            ret = [a,b];
            app.LIMITSlider.Value=ret;
            app.MINNMEditField.Value=ret(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SWEEP')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Q')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.KEYSIGHTCOUPLINGLabel = uilabel(app.UIFigure);
            app.KEYSIGHTCOUPLINGLabel.Text = 'KEYSIGHT COUPLING';
            app.POWERDBMLabel = uilabel(app.UIFigure);
            app.POWERDBMLabel.Text = 'POWER [DBM]';
            app.POWERDBMKnob = uiknob(app.UIFigure, 'discrete');
            app.POWERDBMKnob.Items = {'0', '2', '4', '6', '8', '10', '12'};
            app.POWERDBMKnob.ValueChangedFcn = createCallbackFcn(app, @POWERDBMKnobValueChanged, true);
            app.POWERDBMKnob.Value = '0';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.NAMEEditFieldLabel = uilabel(app.UIFigure);
            app.NAMEEditFieldLabel.Text = 'NAME';
            app.NAMEEditField = uieditfield(app.UIFigure, 'text');
            app.NAMEEditField.Value = 'LT';
            app.WAVELENGTHNMEditFieldLabel = uilabel(app.UIFigure);
            app.WAVELENGTHNMEditFieldLabel.Text = 'WAVELENGTH [NM]';
            app.WAVELENGTHNMEditField = uieditfield(app.UIFigure, 'numeric');
            app.WAVELENGTHNMEditField.Limits = [1500 1600];
            app.WAVELENGTHNMEditField.ValueDisplayFormat = '%.4f';
            app.WAVELENGTHNMEditField.ValueChangedFcn = createCallbackFcn(app, @WAVELENGTHNMEditFieldValueChanged, true);
            app.WAVELENGTHNMEditField.Value = 1550;
            app.SWEEPButton = uibutton(app.UIFigure, 'push');
            app.SWEEPButton.ButtonPushedFcn = createCallbackFcn(app, @SWEEPButtonPushed, true);
            app.SWEEPButton.Text = 'SWEEP';
            app.READYLampLabel = uilabel(app.UIFigure);
            app.READYLampLabel.Text = 'READY';
            app.READYLamp = uilamp(app.UIFigure);
            app.RAWLOSSLAMBDACheckBox = uicheckbox(app.UIFigure);
            app.RAWLOSSLAMBDACheckBox.Enable = 'off';
            app.RAWLOSSLAMBDACheckBox.Text = 'RAW LOSS & LAMBDA';
            app.RAWLOSSLAMBDACheckBox.Value = true;
            app.SWEEPPICTURECheckBox = uicheckbox(app.UIFigure);
            app.SWEEPPICTURECheckBox.Text = 'SWEEP PICTURE';
            app.SWEEPPICTURECheckBox.Value = true;
            app.QANALYSISCheckBox = uicheckbox(app.UIFigure);
            app.QANALYSISCheckBox.Text = 'Q ANALYSIS';
            app.QANALYSISCheckBox.Value = true;
            app.ADDTIMESTAMPCheckBox = uicheckbox(app.UIFigure);
            app.ADDTIMESTAMPCheckBox.Enable = 'off';
            app.ADDTIMESTAMPCheckBox.Text = 'ADD TIMESTAMP';
            app.ADDTIMESTAMPCheckBox.Value = true;
            app.ADDPOWERSTAMPCheckBox = uicheckbox(app.UIFigure);
            app.ADDPOWERSTAMPCheckBox.Enable = 'off';
            app.ADDPOWERSTAMPCheckBox.Text = 'ADD POWER STAMP';
            app.ADDPOWERSTAMPCheckBox.Value = true;
            app.INVERTCheckBox = uicheckbox(app.UIFigure);
            app.INVERTCheckBox.Text = 'INVERT';
            app.QShowButton = uibutton(app.UIFigure, 'push');
            app.QShowButton.ButtonPushedFcn = createCallbackFcn(app, @QShowButtonPushed, true);
            app.QShowButton.Text = 'Q Show';
            app.ParamEditFieldLabel = uilabel(app.UIFigure);
            app.ParamEditFieldLabel.Text = 'Param';
            app.ParamEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParamEditField.Limits = [0 1];
            app.ParamEditField.ValueChangedFcn = createCallbackFcn(app, @ParamEditFieldValueChanged, true);
            app.ParamEditField.Value = 0.003;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '  ---------------------------------------';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '  ---------------------------------------';
            app.RESETButton = uibutton(app.UIFigure, 'state');
            app.RESETButton.ValueChangedFcn = createCallbackFcn(app, @RESETButtonValueChanged, true);
            app.RESETButton.Text = 'RESET';
            app.OPENMONITORButton = uibutton(app.UIFigure, 'push');
            app.OPENMONITORButton.ButtonPushedFcn = createCallbackFcn(app, @OPENMONITORButtonPushed, true);
            app.OPENMONITORButton.Text = 'OPEN MONITOR';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'NOTE1 : In both sweep and on-screen monitor, a reference of a PM patch fiber is compenasated for (Like SANTEC). The monitor on the Laser`s screen is without this compensation.'; 'NOTE2: In Sweep, the power value is allwayes TAREd. In on-screen monitor the TARE can be switched on and off.'};
            app.FullShowCheckBox = uicheckbox(app.UIFigure);
            app.FullShowCheckBox.Text = 'FullShow';
            app.LIMITSliderLabel = uilabel(app.UIFigure);
            app.LIMITSliderLabel.Text = 'LIMIT';
            app.LIMITSlider = uislider(app.UIFigure, 'range');
            app.LIMITSlider.Limits = [1500 1600];
            app.LIMITSlider.ValueChangedFcn = createCallbackFcn(app, @LIMITSliderValueChanged, true);
            app.LIMITSlider.Value = [1500 1600];
            app.STEPPMKnobLabel = uilabel(app.UIFigure);
            app.STEPPMKnobLabel.Text = 'STEP [PM]';
            app.STEPPMKnob = uiknob(app.UIFigure, 'discrete');
            app.STEPPMKnob.Items = {'0.1', '0.2', '0.5', '1'};
            app.STEPPMKnob.ValueChangedFcn = createCallbackFcn(app, @STEPPMKnobValueChanged, true);
            app.STEPPMKnob.Value = '1';
            app.MINNMEditFieldLabel = uilabel(app.UIFigure);
            app.MINNMEditFieldLabel.Text = 'MIN [NM]';
            app.MINNMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MINNMEditField.Limits = [1500 1600];
            app.MINNMEditField.ValueDisplayFormat = '%.4f';
            app.MINNMEditField.ValueChangedFcn = createCallbackFcn(app, @MINNMEditFieldValueChanged, true);
            app.MINNMEditField.Value = 1500;
            app.MAXNMEditFieldLabel = uilabel(app.UIFigure);
            app.MAXNMEditFieldLabel.Text = 'MAX [NM]';
            app.MAXNMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MAXNMEditField.Limits = [1500 1600];
            app.MAXNMEditField.ValueDisplayFormat = '%.4f';
            app.MAXNMEditField.ValueChangedFcn = createCallbackFcn(app, @MAXNMEditFieldValueChanged, true);
            app.MAXNMEditField.Value = 1600;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KeysightCoupling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end