classdef monitorG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TARESwitch         matlab.ui.control.Switch
        TARESwitchLabel    matlab.ui.control.Label
        dBmEditField       matlab.ui.control.NumericEditField
        dBmEditFieldLabel  matlab.ui.control.Label
        POWERMONITORLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)

        function func(app,KEYSIGHT)
            while 1
                try
                    pwr = KEYSIGHT.GetMonitorPower;
                    switch app.TARESwitch.Value
                        case 'Off'
                            app.dBmEditField.Value = pwr;
                        case 'On'
                            SP = KEYSIGHT.getOpticalPower;
                            app.dBmEditField.Value = pwr-SP;
                    end
                catch ME
                    rethrow(ME);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, KEYSIGHT)
            KEYSIGHT.setprints(0);
            KEYSIGHT.flushh;
            app.func(KEYSIGHT)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.POWERMONITORLabel = uilabel(app.UIFigure);
            app.POWERMONITORLabel.Text = 'POWER MONITOR';
            app.dBmEditFieldLabel = uilabel(app.UIFigure);
            app.dBmEditFieldLabel.Text = 'dBm';
            app.dBmEditField = uieditfield(app.UIFigure, 'numeric');
            app.dBmEditField.ValueDisplayFormat = '%.3f';
            app.dBmEditField.Editable = 'off';
            app.TARESwitchLabel = uilabel(app.UIFigure);
            app.TARESwitchLabel.Text = 'TARE?';
            app.TARESwitch = uiswitch(app.UIFigure, 'slider');
            app.TARESwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitorG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end