classdef FULL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Switch                    matlab.ui.control.Switch
        SwitchLabel               matlab.ui.control.Label
        JumppmSpinner             matlab.ui.control.Spinner
        JumppmSpinnerLabel        matlab.ui.control.Label
        RSTButton                 matlab.ui.control.Button
        allowdropsCheckBox        matlab.ui.control.CheckBox
        TrmvGauge                 matlab.ui.control.Gauge
        TrmvGaugeLabel            matlab.ui.control.Label
        trimnmSpinner             matlab.ui.control.Spinner
        trimnmSpinnerLabel        matlab.ui.control.Label
        lockedLamp                matlab.ui.control.Lamp
        lockedLampLabel           matlab.ui.control.Label
        STOPButton                matlab.ui.control.StateButton
        GOButton                  matlab.ui.control.Button
        PIDcuttoffHzSpinner       matlab.ui.control.Spinner
        PIDcuttoffHzSpinnerLabel  matlab.ui.control.Label
        PIDgaindBSpinner          matlab.ui.control.Spinner
        PIDgaindBSpinnerLabel     matlab.ui.control.Label
        WLnmEditField             matlab.ui.control.NumericEditField
        WLnmEditFieldLabel        matlab.ui.control.Label
        POWERSpinner              matlab.ui.control.Spinner
        POWERSpinnerLabel         matlab.ui.control.Label
        PHASEdegSpinner           matlab.ui.control.Spinner
        PHASEdegSpinnerLabel      matlab.ui.control.Label
        SINAMPmvSpinner           matlab.ui.control.Spinner
        SINAMPmvSpinnerLabel      matlab.ui.control.Label
        PRESETSpinner             matlab.ui.control.Spinner
        PRESETSpinnerLabel        matlab.ui.control.Label
        QGauge                    matlab.ui.control.Gauge
        QGaugeLabel               matlab.ui.control.Label
        PicoScope2Lamp            matlab.ui.control.Lamp
        PicoScope2LampLabel       matlab.ui.control.Label
        MOKUGOLamp                matlab.ui.control.Lamp
        MOKUGOLampLabel           matlab.ui.control.Label
        SANTEC570Lamp             matlab.ui.control.Lamp
        SANTEC570LampLabel        matlab.ui.control.Label
        ConnecttodevicesButton    matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SANTEC_ % Description
        PICO_ % Description
        MOKU_ % Description
        tar % Description
        T % Description
        V % Description
        oldpower % Description
        flag=0 % Description
    end

    methods (Access = private)

    end

    methods (Access = public)

        function SETUP(app)
            %% santec
            % Turn on laser
            app.SANTEC_.startLD;
            % Set  power
            app.SANTEC_.setPow(app.POWERSpinner.Value);
            app.oldpower=app.POWERSpinner.Value;
            % open the shutter
            app.SANTEC_.OpenShutter;
            % sweep params
            sweep_rate = 50;
            centerWL = app.WLnmEditField.Value;
            lambda_min=centerWL-1;
            lambda_max=centerWL+1;
            cyc=1;
            del=0;
            app.SANTEC_.setSweep(lambda_min,lambda_max,sweep_rate,cyc,del);

            %% picoscope
            durationS = 2/sweep_rate;
            fs = 100e3; % gonna get 97.6KHz
            app.PICO_.setup(1/fs,durationS*1e3);

            %% moku
            app.MOKU_.set_defaults();
            app.MOKU_.set_output(1,false,false);
            app.MOKU_.set_output(2,false,false);

            app.MOKU_.set_output_limit(1, 'high_limit', 1.2, 'low_limit', -1.2, 'enable', true);
            app.MOKU_.set_frontend(1, 'AC', '1MOhm','0dB');
            app.MOKU_.set_frontend(2, 'DC', '1MOhm','0dB');
            app.MOKU_.set_aux_oscillator('enable',true,'frequency',200,'amplitude',app.SINAMPmvSpinner.Value/1000,'output','Output1','phase_lock',true);
            app.MOKU_.set_demodulation('Modulation','frequency',200,'phase',app.PHASEdegSpinner.Value);
            app.MOKU_.set_scan_oscillator('enable',false,'shape','PositiveRamp','frequency',1,'amplitude',2.4,'output','Output1');
            app.MOKU_.set_filter('shape','Lowpass','low_corner',100e3,'order',4);
            app.MOKU_.set_pid_by_frequency(1,app.PIDgaindBSpinner.Value,'int_crossover',app.PIDcuttoffHzSpinner.Value,'invert', true);
            app.MOKU_.set_setpoint(0);
            app.MOKU_.set_output_offset(1,0);
            disp(app.MOKU_.summary());

            %Set up signal monitoring
            % Configure monitor points to Input 1 and Input 2
            app.MOKU_.set_monitor(1,'Output1');
            app.MOKU_.set_monitor(2,'Input2');
            % Configure the trigger conditions
            % Trigger on Probe A, rising edge, 0V
            %app.MOKU_.enable_conditional_trigger(0);
            app.MOKU_.set_trigger('type','Edge', 'source','ProbeA', 'level',0);
            % View +- 1 ms i.e. trigger in the centre
            app.MOKU_.set_timebase(-0.05e-3,0.05e-3);

            app.SANTEC_.SETMOD;
        end

        function MOKUread(app)     
            data = app.MOKU_.get_data('wait_reacquire',false,'wait_complete',true);
            app.V=1000*mean(data.ch1); % output 1
            app.T=1000*mean(data.ch2); % input 2 (PD voltage DC coupled)
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttodevicesButton
        function ConnecttodevicesButtonPushed(app, event)
            app.STOPButton.Enable=0;
            app.GOButton.Enable=0;
            pause(0.1);
            try
                app.PICO_.delete();
            catch
            end
            app.PicoScope2Lamp.Color=[1 0 0]; pause(0.1);
            try
                app.SANTEC_.delete;
            catch
            end
            app.SANTEC570Lamp.Color=[1 0 0];pause(0.1);
            try
                app.MOKU_.relinquish_ownership();
            catch
            end
            app.MOKUGOLamp.Color=[1 0 0];pause(0.1);

            if app.Switch.Value=="550"
                app.SANTEC_=SANTEC_550();
            else
                app.SANTEC_=SANTEC_570();
            end
            app.SANTEC570Lamp.Color=[0 1 0]; pause(0.1);
            try
                app.MOKU_=MokuLaserLockBox('192.168.73.1',true);
                app.MOKU_.set_output(1,false,false);
            catch
                return;
            end
            app.MOKUGOLamp.Color=[0 1 0]; pause(0.1);
            app.PICO_=PicoScope2_Block();
            app.PicoScope2Lamp.Color=[0 1 0]; pause(0.1);
            
            app.flag=0;
            app.STOPButton.Enable=1;
            app.GOButton.Enable=1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.PICO_.delete();
            catch
            end
            try
                app.SANTEC_.delete;
            catch
            end
            try
                app.MOKU_.set_output(1,false,false);
                app.MOKU_.relinquish_ownership();
            catch
            end
            delete(app);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.STOPButton.Value=0;
            app.SETUP;
            app.flag = 0;
            app.GOButton.Enable=0;

            centerWL = app.WLnmEditField.Value;
            lambda_min=centerWL-1;
            lambda_max=centerWL+1;

            while app.STOPButton.Value==0
                if app.flag==0
                    app.SANTEC_.SweepGO; [PD, TRIG] = app.PICO_.read();
                    [a,b]=max(PD);
                    b=mean(find(PD==a)); % refined
                    if a>50
                        %[~,count]=zerocrossrate(TRIG(1:round(b))-1500);
                        % 50 below is sweep rate, weird number is sampling
                        % rate
                        app.tar = app.WLnmEditField.Value -1 + 50*b*0.01024e-3 + app.trimnmSpinner.Value;
                        app.T=0; ctr=0;
                        while (app.T < 0.5*a)
                            app.SANTEC_.setWl(app.tar);
                            pause(0.15);
                            app.MOKUread;
                            ctr=ctr+1;
                            if ctr>5
                                break;
                            end
                        end
                        app.MOKU_.set_output(1,true,true);
                        app.flag=1;
                        app.lockedLamp.Color=[0 1 0];
                        % Q
                        left = find(PD(1:b)>0.5*a,1);
                        right = b + find(PD(b:end)<0.5*a,1);
                        try
                            app.QGauge.Value=(app.T/a)*0.001*app.tar/(0.01*(right-left)/12);
                        catch
                        end
                        app.TrmvGauge.Value=a;
                        app.PRESETSpinner.Enable=0;
                        app.SINAMPmvSpinner.Enable=0;
                        app.PHASEdegSpinner.Enable=0;
                        %app.WLnmEditField.Value=app.tar;
                        wllist=linspace(lambda_min,lambda_max,length(PD));
                        plot(app.UIAxes,wllist,PD);
                        hold(app.UIAxes,"on");
                        xline(app.UIAxes,app.tar,'Color','r','LineWidth',2,'LineStyle','--');
                        yline(app.UIAxes,app.T,'Color','r','LineWidth',2,'LineStyle','--');
                        hold(app.UIAxes,"off");
                        axis(app.UIAxes,"tight");
                        pause(1);
                    else % a<50 failed sweep
                        pause(1);
                        continue;
                    end
                end
                app.MOKUread;
                app.TrmvGauge.Value=app.T;
                if app.T > 0.5 * a
                    if app.T>a
                        a=app.T;
                    end
                else
                    %  if (abs(app.V)>1150) || (app.allowdropsCheckBox.Value==0)
                    app.flag=0;
                    app.MOKU_.set_output(1,false,false);
                    app.lockedLamp.Color=[1 0 0];
                    pause(0.01);
                    continue;
                    % end
                end
                if abs(app.POWERSpinner.Value-app.oldpower)>0
                    app.SANTEC_.setPow(app.POWERSpinner.Value);
                    app.oldpower=app.POWERSpinner.Value;
                    app.MOKUread;
                    a=app.T;
                end
                if abs(app.V)>1150
                    if app.V>0
                        app.tar=app.tar-app.JumppmSpinner.Value*1e-3; 
                    else
                        app.tar=app.tar+app.JumppmSpinner.Value*1e-3;  
                    end
                    %app.WLnmEditField.Value=app.tar;
                    pause(1);
                    app.SANTEC_.setWl(app.tar);
                    pause(0.015);
                    app.MOKU_.set_output(1,false,false);
                    app.MOKU_.set_output(1,true,true);
                    pause(2);
                end
                pause(0.01);
            end
            app.lockedLamp.Color=[1 0 0];
            pause(0.1);
            app.flag=0;
            app.MOKU_.set_output(1,false,false);pause(0.6);
            app.MOKUread;
            app.TrmvGauge.Value=app.T;
            app.GOButton.Enable=1;
            app.PRESETSpinner.Enable=1;
            app.SINAMPmvSpinner.Enable=1;
            app.PHASEdegSpinner.Enable=1;
        end

        % Value changed function: PRESETSpinner
        function PRESETSpinnerValueChanged(app, event)
            value = app.PRESETSpinner.Value;
            switch value
                case 0
                    app.PIDcuttoffHzSpinner.Value = 70;
                    app.PIDgaindBSpinner.Value = -7;
                case 1
                    app.PIDcuttoffHzSpinner.Value = 80;
                    app.PIDgaindBSpinner.Value = -6;
                case 2
                    app.PIDcuttoffHzSpinner.Value = 90;
                    app.PIDgaindBSpinner.Value = -5;
                case 3
                    app.PIDcuttoffHzSpinner.Value = 100;
                    app.PIDgaindBSpinner.Value = -4;
                case 4
                    app.PIDcuttoffHzSpinner.Value = 110;
                    app.PIDgaindBSpinner.Value = -3;
                case 5
                    app.PIDcuttoffHzSpinner.Value = 120;
                    app.PIDgaindBSpinner.Value = -2;
                case 6
                    app.PIDcuttoffHzSpinner.Value = 130;
                    app.PIDgaindBSpinner.Value = -1;
                case 7
                    app.PIDcuttoffHzSpinner.Value = 140;
                    app.PIDgaindBSpinner.Value = 0;
                case 8
                    app.PIDcuttoffHzSpinner.Value = 150;
                    app.PIDgaindBSpinner.Value = 1;
                case 9
                    app.PIDcuttoffHzSpinner.Value = 160;
                    app.PIDgaindBSpinner.Value = 2;
            end
        end

        % Value changed function: POWERSpinner
        function POWERSpinnerValueChanged(app, event)
            if (app.flag == 0) && (app.GOButton.Enable == 1)
                app.SANTEC_.setPow(app.POWERSpinner.Value);
                app.oldpower=app.POWERSpinner.Value;
            end
        end

        % Button pushed function: RSTButton
        function RSTButtonPushed(app, event)
            app.PRESETSpinner.Enable=1;
            app.SINAMPmvSpinner.Enable=1;
            app.PHASEdegSpinner.Enable=1;
            app.GOButton.Enable=1;
            app.STOPButton.Enable=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'WL [nm]')
            ylabel(app.UIAxes, 'Tr [mv]')
            zlabel(app.UIAxes, 'Z')
            app.ConnecttodevicesButton = uibutton(app.UIFigure, 'push');
            app.ConnecttodevicesButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttodevicesButtonPushed, true);
            app.ConnecttodevicesButton.Text = 'Connect to devices';
            app.SANTEC570LampLabel = uilabel(app.UIFigure);
            app.SANTEC570LampLabel.Text = 'SANTEC 570';
            app.SANTEC570Lamp = uilamp(app.UIFigure);
            app.MOKUGOLampLabel = uilabel(app.UIFigure);
            app.MOKUGOLampLabel.Text = 'MOKU GO';
            app.MOKUGOLamp = uilamp(app.UIFigure);
            app.PicoScope2LampLabel = uilabel(app.UIFigure);
            app.PicoScope2LampLabel.Text = 'PicoScope 2';
            app.PicoScope2Lamp = uilamp(app.UIFigure);
            app.QGaugeLabel = uilabel(app.UIFigure);
            app.QGaugeLabel.Text = 'Q';
            app.QGauge = uigauge(app.UIFigure, 'circular');
            app.QGauge.Limits = [0 300];
            app.PRESETSpinnerLabel = uilabel(app.UIFigure);
            app.PRESETSpinnerLabel.Text = 'PRESET';
            app.PRESETSpinner = uispinner(app.UIFigure);
            app.PRESETSpinner.Limits = [0 9];
            app.PRESETSpinner.ValueChangedFcn = createCallbackFcn(app, @PRESETSpinnerValueChanged, true);
            app.PRESETSpinner.Value = 5;
            app.SINAMPmvSpinnerLabel = uilabel(app.UIFigure);
            app.SINAMPmvSpinnerLabel.Text = 'SIN AMP mv';
            app.SINAMPmvSpinner = uispinner(app.UIFigure);
            app.SINAMPmvSpinner.Step = 10;
            app.SINAMPmvSpinner.Limits = [10 100];
            app.SINAMPmvSpinner.Value = 40;
            app.PHASEdegSpinnerLabel = uilabel(app.UIFigure);
            app.PHASEdegSpinnerLabel.Text = 'PHASE deg';
            app.PHASEdegSpinner = uispinner(app.UIFigure);
            app.PHASEdegSpinner.Step = 5;
            app.PHASEdegSpinner.Limits = [0 360];
            app.POWERSpinnerLabel = uilabel(app.UIFigure);
            app.POWERSpinnerLabel.Text = 'POWER';
            app.POWERSpinner = uispinner(app.UIFigure);
            app.POWERSpinner.Step = 0.5;
            app.POWERSpinner.Limits = [0 13];
            app.POWERSpinner.ValueChangedFcn = createCallbackFcn(app, @POWERSpinnerValueChanged, true);
            app.POWERSpinner.Value = 5;
            app.WLnmEditFieldLabel = uilabel(app.UIFigure);
            app.WLnmEditFieldLabel.Text = 'WL nm';
            app.WLnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WLnmEditField.Limits = [1500 1630];
            app.WLnmEditField.ValueDisplayFormat = '%.4f';
            app.WLnmEditField.Value = 1588;
            app.PIDgaindBSpinnerLabel = uilabel(app.UIFigure);
            app.PIDgaindBSpinnerLabel.Enable = 'off';
            app.PIDgaindBSpinnerLabel.Text = 'PID gain dB';
            app.PIDgaindBSpinner = uispinner(app.UIFigure);
            app.PIDgaindBSpinner.Limits = [-10 0];
            app.PIDgaindBSpinner.Editable = 'off';
            app.PIDgaindBSpinner.Enable = 'off';
            app.PIDgaindBSpinner.Value = -3;
            app.PIDcuttoffHzSpinnerLabel = uilabel(app.UIFigure);
            app.PIDcuttoffHzSpinnerLabel.Enable = 'off';
            app.PIDcuttoffHzSpinnerLabel.Text = 'PID cuttoff Hz';
            app.PIDcuttoffHzSpinner = uispinner(app.UIFigure);
            app.PIDcuttoffHzSpinner.Limits = [40 160];
            app.PIDcuttoffHzSpinner.Editable = 'off';
            app.PIDcuttoffHzSpinner.Enable = 'off';
            app.PIDcuttoffHzSpinner.Value = 90;
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Enable = 'off';
            app.GOButton.Text = 'GO';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.lockedLampLabel = uilabel(app.UIFigure);
            app.lockedLampLabel.Text = 'locked';
            app.lockedLamp = uilamp(app.UIFigure);
            app.trimnmSpinnerLabel = uilabel(app.UIFigure);
            app.trimnmSpinnerLabel.Text = 'trim nm';
            app.trimnmSpinner = uispinner(app.UIFigure);
            app.trimnmSpinner.Step = 0.005;
            app.trimnmSpinner.ValueDisplayFormat = '%.4f';
            app.trimnmSpinner.Value = -0.015;
            app.TrmvGaugeLabel = uilabel(app.UIFigure);
            app.TrmvGaugeLabel.Text = 'Tr mv';
            app.TrmvGauge = uigauge(app.UIFigure, 'circular');
            app.TrmvGauge.Limits = [0 2000];
            app.allowdropsCheckBox = uicheckbox(app.UIFigure);
            app.allowdropsCheckBox.Visible = 'off';
            app.allowdropsCheckBox.Text = 'allow drops';
            app.RSTButton = uibutton(app.UIFigure, 'push');
            app.RSTButton.ButtonPushedFcn = createCallbackFcn(app, @RSTButtonPushed, true);
            app.RSTButton.Text = 'RST';
            app.JumppmSpinnerLabel = uilabel(app.UIFigure);
            app.JumppmSpinnerLabel.Text = 'Jump pm';
            app.JumppmSpinner = uispinner(app.UIFigure);
            app.JumppmSpinner.Step = 10;
            app.JumppmSpinner.ValueDisplayFormat = '%.0f';
            app.JumppmSpinner.Value = 40;
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'550', '570'};
            app.Switch.Value = '570';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FULL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end