classdef MOKU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LIMITERSwitch               matlab.ui.control.Switch
        LIMITERSwitchLabel          matlab.ui.control.Label
        RecommendedPresetGauge      matlab.ui.control.NinetyDegreeGauge
        RecommendedPresetLabel      matlab.ui.control.Label
        QfactorKGauge               matlab.ui.control.NinetyDegreeGauge
        QfactorKGaugeLabel          matlab.ui.control.Label
        STOPButton                  matlab.ui.control.StateButton
        PIDcuttoffHzEditField       matlab.ui.control.NumericEditField
        PIDcuttoffHzEditFieldLabel  matlab.ui.control.Label
        PIDgaindBEditField          matlab.ui.control.NumericEditField
        PIDgaindBEditFieldLabel     matlab.ui.control.Label
        PHASEdegEditField           matlab.ui.control.NumericEditField
        PHASEdegEditFieldLabel      matlab.ui.control.Label
        OSCAMPmVEditField           matlab.ui.control.NumericEditField
        OSCAMPmVEditFieldLabel      matlab.ui.control.Label
        PRESETSpinner               matlab.ui.control.Spinner
        PRESETSpinnerLabel          matlab.ui.control.Label
        LaserLockBoxLabel           matlab.ui.control.Label
        MOKUGOButton                matlab.ui.control.Button
        EnabledLamp                 matlab.ui.control.Lamp
        EnabledLampLabel            matlab.ui.control.Label
        RunningLamp                 matlab.ui.control.Lamp
        RunningLampLabel            matlab.ui.control.Label
        ConnectedLamp               matlab.ui.control.Lamp
        ConnectedLampLabel          matlab.ui.control.Label
    end


    properties (Access = public)
        inst % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MOKUGOButton
        function MOKUGOButtonPushed(app, event)
            app.STOPButton.Value = 0;
            app.OSCAMPmVEditField.Enable = 0;
            app.PIDcuttoffHzEditField.Enable = 0;
            app.PIDgaindBEditField.Enable = 0;
            app.PHASEdegEditField.Enable = 0;
            app.PRESETSpinner.Enable = 0;

            app.ConnectedLamp.Color = [1 0 0];
            app.RunningLamp.Color = [1 0 0];
            app.EnabledLamp.Color = [1 0 0];
            %% Connect Moku
            if exist('i','var')
                i.relinquish_ownership();
                clear i;
                app.ConnectedLamp.Color = [1 0 0];
                app.RunningLamp.Color = [1 0 0];
                pause(0.2);
            end
            i = MokuLaserLockBox('192.168.73.1',true);
            app.ConnectedLamp.Color = [0 1 0];
            pause(0.2);
            % reset
            i.set_defaults();
            i.set_output(1,false,false);
            flag_on = 0;
            %% setup

            % parameters of optics: LT2.1 gauss with Q~170. PDgain is 50.
            switch app.LIMITERSwitch.Value
                case '1.5'
                    LIM=1.5;
                case '1.2'
                    LIM=1.2;
            end
            i.set_output_limit(1, 'high_limit', LIM, 'low_limit', -LIM, 'enable', true);
            i.set_frontend(1, 'AC', '1MOhm','0dB');
            i.set_frontend(2, 'DC', '1MOhm','0dB');
            i.set_aux_oscillator('enable',true,'frequency',200,'amplitude',app.OSCAMPmVEditField.Value/1000,'output','Output1','phase_lock',true);
            i.set_demodulation('Modulation','frequency',200,'phase',app.PHASEdegEditField.Value);
            i.set_scan_oscillator('enable',false,'shape','PositiveRamp','frequency',1,'amplitude',3,'output','Output1');
            i.set_filter('shape','Lowpass','low_corner',100e3,'order',4);
            i.set_pid_by_frequency(1,app.PIDgaindBEditField.Value,'int_crossover',app.PIDcuttoffHzEditField.Value,'invert', true);
            i.set_setpoint(0);
            i.set_output_offset(1,0);
            disp(i.summary());

            %% Enable the output channels
            i.set_output(1,true,true); flag_on=1;
            i.set_output(2,false,false);
            app.RunningLamp.Color = [0 1 0];
            app.EnabledLamp.Color = [0 1 0];
            pause(0.2);
            sound(sin(2*(1:700)))

            app.STOPButton.Enable = 1;
            app.STOPButton.Enable = 1;
            %% Set up signal monitoring
            % Configure monitor points to Input 1 and Input 2
            i.set_monitor(1,'Input1');
            i.set_monitor(2,'Input2');
            % Configure the trigger conditions
            % Trigger on Probe A, rising edge, 0V
            i.set_trigger('type','Edge', 'source','ProbeA', 'level',0);
            % View +- 1 ms i.e. trigger in the centre
            i.set_timebase(-0.02e-3,0.02e-3);

            app.inst = i;

            try
                while app.STOPButton.Value==0
                    data = i.get_data('wait_complete',true);
                    %myout=round(1000*mean(data.ch2));
                    flag=round(1000*mean(data.ch2));
                    try
                        app.QfactorKGauge.Value = (flag/5000)*255;
                        app.RecommendedPresetGauge.Value = min(6,round(255/app.QfactorKGauge.Value));
                    catch
                    end
                    pause(0.1);
                    if (flag<500) && (flag_on==1)
                        i.set_output(1,false,false);
                        flag_on=0;
                        app.EnabledLamp.Color = [1 0 0];
                        sound(sin(1:700))
                    end
                    if (flag>=500) && (flag_on==0)
                        i.set_output(1,true,true);
                        flag_on=1;
                        app.EnabledLamp.Color = [0 1 0];
                        sound(sin(2*(1:700)));
                    end
                end
            catch m
                app.MOKUGOButtonPushed();
            end
            app.OSCAMPmVEditField.Enable = 1;
            app.PIDcuttoffHzEditField.Enable = 1;
            app.PIDgaindBEditField.Enable = 1;
            app.PHASEdegEditField.Enable = 1;
            app.PRESETSpinner.Enable = 1;
        end

        % Value changed function: PRESETSpinner
        function PRESETSpinnerValueChanged(app, event)
            value = app.PRESETSpinner.Value;
            switch value
                case 0
                    app.PIDcuttoffHzEditField.Value = 70;
                    app.PIDgaindBEditField.Value = -7;
                case 1
                    app.PIDcuttoffHzEditField.Value = 80;
                    app.PIDgaindBEditField.Value = -6;
                case 2
                    app.PIDcuttoffHzEditField.Value = 90;
                    app.PIDgaindBEditField.Value = -5;
                case 3
                    app.PIDcuttoffHzEditField.Value = 100;
                    app.PIDgaindBEditField.Value = -4;
                case 4
                    app.PIDcuttoffHzEditField.Value = 110;
                    app.PIDgaindBEditField.Value = -3;
                case 5
                    app.PIDcuttoffHzEditField.Value = 120;
                    app.PIDgaindBEditField.Value = -2;
                case 6
                    app.PIDcuttoffHzEditField.Value = 130;
                    app.PIDgaindBEditField.Value = -1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectedLampLabel = uilabel(app.UIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.EnabledLampLabel = uilabel(app.UIFigure);
            app.EnabledLampLabel.Text = 'Enabled';
            app.EnabledLamp = uilamp(app.UIFigure);
            app.MOKUGOButton = uibutton(app.UIFigure, 'push');
            app.MOKUGOButton.ButtonPushedFcn = createCallbackFcn(app, @MOKUGOButtonPushed, true);
            app.MOKUGOButton.Text = 'MOKU;GO!';
            app.LaserLockBoxLabel = uilabel(app.UIFigure);
            app.LaserLockBoxLabel.Text = 'Laser Lock Box';
            app.PRESETSpinnerLabel = uilabel(app.UIFigure);
            app.PRESETSpinnerLabel.Text = 'PRESET';
            app.PRESETSpinner = uispinner(app.UIFigure);
            app.PRESETSpinner.Limits = [0 6];
            app.PRESETSpinner.ValueChangedFcn = createCallbackFcn(app, @PRESETSpinnerValueChanged, true);
            app.PRESETSpinner.Value = 3;
            app.OSCAMPmVEditFieldLabel = uilabel(app.UIFigure);
            app.OSCAMPmVEditFieldLabel.Text = 'OSC AMP  mV';
            app.OSCAMPmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.OSCAMPmVEditField.Limits = [10 100];
            app.OSCAMPmVEditField.Value = 45;
            app.PHASEdegEditFieldLabel = uilabel(app.UIFigure);
            app.PHASEdegEditFieldLabel.Text = 'PHASE deg';
            app.PHASEdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.PHASEdegEditField.Limits = [0 360];
            app.PHASEdegEditField.Value = 20;
            app.PIDgaindBEditFieldLabel = uilabel(app.UIFigure);
            app.PIDgaindBEditFieldLabel.Text = 'PID gain dB';
            app.PIDgaindBEditField = uieditfield(app.UIFigure, 'numeric');
            app.PIDgaindBEditField.Limits = [-10 0];
            app.PIDgaindBEditField.Value = -4;
            app.PIDcuttoffHzEditFieldLabel = uilabel(app.UIFigure);
            app.PIDcuttoffHzEditFieldLabel.Text = 'PID cuttoff Hz';
            app.PIDcuttoffHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.PIDcuttoffHzEditField.Limits = [40 160];
            app.PIDcuttoffHzEditField.Value = 100;
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.Text = 'STOP';
            app.QfactorKGaugeLabel = uilabel(app.UIFigure);
            app.QfactorKGaugeLabel.Text = 'Q factor [K]';
            app.QfactorKGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.QfactorKGauge.Limits = [0 260];
            app.QfactorKGauge.MajorTicks = [0 50 80 110 150 200 250];
            app.RecommendedPresetLabel = uilabel(app.UIFigure);
            app.RecommendedPresetLabel.Text = 'Recommended Preset';
            app.RecommendedPresetGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.RecommendedPresetGauge.Limits = [0 6];
            app.RecommendedPresetGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.RecommendedPresetGauge.ScaleDirection = 'counterclockwise';
            app.RecommendedPresetGauge.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6];
            app.LIMITERSwitchLabel = uilabel(app.UIFigure);
            app.LIMITERSwitchLabel.Text = 'LIMITER';
            app.LIMITERSwitch = uiswitch(app.UIFigure, 'slider');
            app.LIMITERSwitch.Items = {'1.2', '1.5'};
            app.LIMITERSwitch.Value = '1.2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOKU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end