classdef kwavehand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TMPButton                      matlab.ui.control.Button
        saverunButton                  matlab.ui.control.Button
        ElipticalarteryCheckBox        matlab.ui.control.CheckBox
        LaserNoiseCheckBox             matlab.ui.control.CheckBox
        IluminationFormHDropDown       matlab.ui.control.DropDown
        IluminationFormHDropDownLabel  matlab.ui.control.Label
        COMPENSCheckBox                matlab.ui.control.CheckBox
        Image                          matlab.ui.control.Image
        multisensmodeSwitch            matlab.ui.control.Switch
        multisensmodeSwitchLabel       matlab.ui.control.Label
        optmodelengthumSpinner         matlab.ui.control.Spinner
        optmodelengthumSpinnerLabel    matlab.ui.control.Label
        alphadegSpinner                matlab.ui.control.Spinner
        alphadegSpinnerLabel           matlab.ui.control.Label
        AddabsorbersnoiseCheckBox      matlab.ui.control.CheckBox
        HeatingfunctionKnob            matlab.ui.control.DiscreteKnob
        HeatingfunctionKnobLabel       matlab.ui.control.Label
        attenCompifpossibleCheckBox    matlab.ui.control.CheckBox
        AddNoise16dBSNRCheckBox        matlab.ui.control.CheckBox
        HydrogeloversensorCheckBox     matlab.ui.control.CheckBox
        BiocorrectacousticheterogeneityofmediumCheckBox  matlab.ui.control.CheckBox
        REPLAYButton                   matlab.ui.control.Button
        ReadytoreplayLamp              matlab.ui.control.Lamp
        ReadytoreplayLampLabel         matlab.ui.control.Label
        vesseldynamicsSwitch           matlab.ui.control.Switch
        vesseldynamicsSwitchLabel      matlab.ui.control.Label
        OverlayextremalinesCheckBox    matlab.ui.control.CheckBox
        MaxFreqHzEditField             matlab.ui.control.NumericEditField
        MaxFreqHzEditFieldLabel        matlab.ui.control.Label
        TemporalConfigLabel            matlab.ui.control.Label
        RUNPULSEButton                 matlab.ui.control.StateButton
        samplesEditField               matlab.ui.control.NumericEditField
        samplesEditFieldLabel          matlab.ui.control.Label
        RedwhenAVconflictLamp          matlab.ui.control.Lamp
        RedwhenAVconflictLampLabel     matlab.ui.control.Label
        mindimmEditField               matlab.ui.control.NumericEditField
        mindimmEditFieldLabel          matlab.ui.control.Label
        maxdimmEditField               matlab.ui.control.NumericEditField
        maxdimmEditFieldLabel          matlab.ui.control.Label
        SetrefrencesystemButton        matlab.ui.control.Button
        maxcompressionSpinner          matlab.ui.control.Spinner
        maxcompressionSpinnerLabel     matlab.ui.control.Label
        mincompressionSpinner          matlab.ui.control.Spinner
        mincompressionSpinnerLabel     matlab.ui.control.Label
        FsSpinner                      matlab.ui.control.Spinner
        FsSpinnerLabel                 matlab.ui.control.Label
        BPMSpinner                     matlab.ui.control.Spinner
        BPMSpinnerLabel                matlab.ui.control.Label
        RUNSIMButton                   matlab.ui.control.StateButton
        dmKnob                         matlab.ui.control.DiscreteKnob
        dmKnobLabel                    matlab.ui.control.Label
        TfinalusSlider                 matlab.ui.control.Slider
        TfinalusSliderLabel            matlab.ui.control.Label
        sensdistmmSlider               matlab.ui.control.Slider
        sensdistmmSliderLabel          matlab.ui.control.Label
        scanwidthmmSlider              matlab.ui.control.Slider
        scanwidthmmSliderLabel         matlab.ui.control.Label
        RveinintSlider                 matlab.ui.control.Slider
        RveinintSliderLabel            matlab.ui.control.Label
        LveinintSlider                 matlab.ui.control.Slider
        LveinintSliderLabel            matlab.ui.control.Label
        LveindiammmSlider              matlab.ui.control.Slider
        LveindiammmSliderLabel         matlab.ui.control.Label
        RveindiammmSlider              matlab.ui.control.Slider
        RveindiammmSliderLabel         matlab.ui.control.Label
        ArterydiametermmSlider         matlab.ui.control.Slider
        ArterydiametermmSliderLabel    matlab.ui.control.Label
        AVangleSlider                  matlab.ui.control.Slider
        AVangleLabel                   matlab.ui.control.Label
        AVseperationmmSliderLabel      matlab.ui.control.Label
        AVseperationmmSlider           matlab.ui.control.Slider
        UIAxes3                        matlab.ui.control.UIAxes
        CenterPanel                    matlab.ui.container.Panel
        POSSELLabel                    matlab.ui.control.Label
        POSSELSlider                   matlab.ui.control.Slider
        TemporalprocessingshowingLamp  matlab.ui.control.Lamp
        TemporalprocessingshowingLampLabel  matlab.ui.control.Label
        DiameterdeltammEditField       matlab.ui.control.NumericEditField
        DiameterdeltammEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        UIAxes9                        matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TimeReversalCheckBox           matlab.ui.control.CheckBox
        SatSourceKnob                  matlab.ui.control.DiscreteKnob
        SatSourceKnobLabel             matlab.ui.control.Label
        VEINSATEditField               matlab.ui.control.NumericEditField
        VEINSATEditFieldLabel          matlab.ui.control.Label
        ARTSATEditField                matlab.ui.control.NumericEditField
        ARTSATEditFieldLabel           matlab.ui.control.Label
        EstimationsLabel               matlab.ui.control.Label
        OPOLabel                       matlab.ui.control.Label
        CRSlider                       matlab.ui.control.Slider
        CRSliderLabel                  matlab.ui.control.Label
        converttoGSCheckBox            matlab.ui.control.CheckBox
        ABSformaskCheckBox             matlab.ui.control.CheckBox
        SpectralExtractionButton       matlab.ui.control.StateButton
        SpectralConfigLabel_2          matlab.ui.control.Label
        playmaskeddynamicsButton       matlab.ui.control.StateButton
        CALCULATEButton                matlab.ui.control.Button
        MethodKnob                     matlab.ui.control.DiscreteKnob
        MethodKnobLabel                matlab.ui.control.Label
        wavelength3Spinner             matlab.ui.control.Spinner
        wavelength3SpinnerLabel        matlab.ui.control.Label
        wavelength2Spinner             matlab.ui.control.Spinner
        wavelength2SpinnerLabel        matlab.ui.control.Label
        wavelength1Spinner             matlab.ui.control.Spinner
        wavelength1SpinnerLabel        matlab.ui.control.Label
        RVO2Spinner                    matlab.ui.control.Spinner
        RVO2SpinnerLabel               matlab.ui.control.Label
        LVO2Spinner                    matlab.ui.control.Spinner
        LVO2SpinnerLabel               matlab.ui.control.Label
        AO2Spinner                     matlab.ui.control.Spinner
        AO2SpinnerLabel                matlab.ui.control.Label
        ReconstractionLabel            matlab.ui.control.Label
        reconmodeSwitch                matlab.ui.control.Switch
        reconmodeSwitchLabel           matlab.ui.control.Label
        numofsensorsSpinner            matlab.ui.control.Spinner
        numofsensorsSpinnerLabel       matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        setlikearteryMenu              matlab.ui.container.Menu
        ContextMenu2                   matlab.ui.container.ContextMenu
        setlikeRMenu                   matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        setlikeRMenu_2                 matlab.ui.container.Menu
        ContextMenu4                   matlab.ui.container.ContextMenu
        RUNMenu                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
    end

    properties (Access = private)
        Diameter 
        Dist 
        d 
        VRint 
        VLint 
        VLdiam 
        VRdiam 
        separation 
        angle 
        datap 
        datat 
        Toastflag 
        numofsensors 
        reconmode 
        dt 
        RefDiam 
        layout 
        recon_res 
        RefSep 
        replaydata 
        medium 
        hydrogel 
        attenComp 
        TemporalMode % 0 no var mask, 1 calculated var mask, 2 dynamic replay with mask
        VarMask 
        diamlist 
        replayframe 
        Multispect 
        d_ratio 
        t_end 
        senslength 
        pos2show 
        Spectmask 
        layoutRAW 
        reconres1
        reconres2
        reconres3
    end

    methods (Access = private)

        function runsim(app)
            
            if app.TemporalMode % undo possiable prev temporal calc
                app.TemporalMode = 0;
                app.TemporalprocessingshowingLamp.Color=[1 0 0];
                title(app.UIAxes, 'sinogram');
                title(app.UIAxes2, 'pressure (t)')
                app.UIAxes2.YLim = [-0.6 0.6];
                app.playmaskeddynamicsButton.Visible = 0;
                app.ThresholdEditField.Visible = 0;
                app.ThresholdEditFieldLabel.Visible = 0;
                app.DiameterdeltammEditField.Visible = 0;
                app.DiameterdeltammEditFieldLabel.Visible = 0;
                app.CALCULATEButton.Enable = 'off';
                app.CALCULATEButton.BackgroundColor=[1,0,0];
                app.VarMask=[];
            end

            if app.medium == 1 % sim fucks up when d too small and there is bone (CFL issues)
                if str2double(app.dmKnob.Value)<5e-5
                    app.dmKnob.Value = '5e-5'; 
                    app.MaxFreqHzEditField.Value = 1580 / (2*app.d);
                end
            end

            app.d = str2double(app.dmKnob.Value);

            % prep struct for kwave function
            inp.Diameter=app.Diameter;
            inp.Dist=app.Dist;
            inp.d=app.d;
            inp.VLint=app.VLint;
            inp.VRint=app.VRint;
            inp.VLdiam=app.VLdiam;
            inp.VRdiam=app.VRdiam;
            inp.separation=app.separation;
            inp.angle=app.angle;
            inp.t_end=app.t_end;
            inp.lengthsens=app.senslength;
            inp.toastflag=app.Toastflag;
            inp.medium=app.medium;
            inp.hydrogel=app.hydrogel;
            inp.attenComp=app.attenComp;
            inp.absorbersnoise = app.AddabsorbersnoiseCheckBox.Value;
            inp.lasernoise = app.LaserNoiseCheckBox.Value;
            inp.eliptical = app.ElipticalarteryCheckBox.Value;
            % spectral part
            inp.wavelengths = [app.wavelength1Spinner.Value app.wavelength2Spinner.Value app.wavelength3Spinner.Value];
            inp.saturations = [app.AO2Spinner.Value app.LVO2Spinner.Value app.RVO2Spinner.Value];
            inp.Multispect = app.Multispect;
            inp.Hshape = app.IluminationFormHDropDown.Value;

            % run kwave forawrd
            [app.datat,app.datap,app.layout] = Kwave2DForGUI(inp);
            app.dt=app.datat(5)-app.datat(4);

            % layout handling for sat estimation
            if app.Multispect
                layout750=app.layout(:,:,1);
                layout800=app.layout(:,:,2);
                layout900=app.layout(:,:,3);
                TMPsensormask=(layout750==1);
                layout750(TMPsensormask)=0;
                layout800(TMPsensormask)=0;
                layout900(TMPsensormask)=0;
                app.layoutRAW = cat(3,layout750,layout800,layout900);
                layout750 = TMPsensormask + mat2gray(layout750);
                layout800 = TMPsensormask + mat2gray(layout800);
                layout900 = TMPsensormask + mat2gray(layout900);
                app.layout = cat(3,layout750,layout800,layout900);
            end

            if app.COMPENSCheckBox.Value
                depth=app.datat*1580;
                corr = 1+10*depth.*exp(300*depth);
                applicator = repmat(corr,size(app.datap,1),1);
                app.datap=app.datap.*applicator;
            end

            if app.AddNoise16dBSNRCheckBox.Value
                snr=16; % dB
                app.datap=addNoise(app.datap, snr);
            end

            if app.Toastflag == 2 && app.Multispect == 0 % boost sig intensity due to low mcmatlab H
                app.datap = app.datap * 1.5;
            end

            % mode length and array angle error
            if app.alphadegSpinner.Value > 0
                app.Image.ImageSource = imread('X (3).PNG');
                app.multisensmodeSwitch.Visible = 1;
                alpha = app.alphadegSpinner.Value; % angle of error [deg]
                L = app.optmodelengthumSpinner.Value * 1e-6; % length of mode [m]
                kernelLenth_ = L*sind(alpha); % [m]
                kernelLenth = max(1,round(kernelLenth_ / app.d)); % [pix]
                kernel = ones(kernelLenth,1)/kernelLenth;
                try
                    app.datap = conv2(app.datap, kernel, 'same');
                catch % app.Multispect == 1
                    app.datap(:,:,1) = conv2(app.datap(:,:,1), kernel, 'same');
                    app.datap(:,:,2) = conv2(app.datap(:,:,2), kernel, 'same');
                    app.datap(:,:,3) = conv2(app.datap(:,:,3), kernel, 'same');
                end
                switch app.multisensmodeSwitch.Value
                    case 'scan'
                        app.d_ratio = 1;
                    case 'array'
                        app.Image.ImageSource = imread('X (4).PNG');
                        x_ = 0.5*app.senslength*(1-cosd(alpha)); % to trim [m]
                        x = round(x_ / app.d); % [pix]
                        app.datap(1:x,:,:)=[];
                        app.datap(end-x:end,:,:)=[];
                        app.d_ratio = round(size(app.datap,1)/(1+round(app.senslength/app.d))); % applied before recon
                        try
                            app.datap = imresize(app.datap,[1+round(app.senslength/app.d) size(app.datap,2)],'nearest');
                        catch % app.Multispect == 1
                            app.datap(:,:,1) = imresize(app.datap(:,:,1),[1+round(app.senslength/app.d) size(app.datap,2)],'nearest');
                            app.datap(:,:,2) = imresize(app.datap(:,:,2),[1+round(app.senslength/app.d) size(app.datap,2)],'nearest');
                            app.datap(:,:,3) = imresize(app.datap(:,:,3),[1+round(app.senslength/app.d) size(app.datap,2)],'nearest');
                        end
                    otherwise
                end
            elseif app.optmodelengthumSpinner.Value>0
                app.multisensmodeSwitch.Visible = 0;
                app.Image.ImageSource = imread('X (2).PNG');
            else
                app.d_ratio = 1;
                app.multisensmodeSwitch.Visible = 0;
                app.Image.ImageSource = imread('X (1).PNG');
            end

            % spectral masking
            try
                sensor_data750 = app.datap(:,:,1);
                sensor_data800 = app.datap(:,:,2);
                sensor_data900 = app.datap(:,:,3);
                if app.ABSformaskCheckBox.Value
                    rgbImagep = app.makeRGB(abs(sensor_data750),abs(sensor_data800),abs(sensor_data900),1);
                else
                    rgbImagep = app.makeRGB((sensor_data750),(sensor_data800),(sensor_data900),1);
                end
                YCBCR = rgb2ycbcr(rgbImagep);
                Y = YCBCR(:,:,1);
                CB = YCBCR(:,:,2);
                CR = YCBCR(:,:,3);
                TH=app.CRSlider.Value/255;
                app.Spectmask=CR>TH;
                sensor_data750(app.Spectmask)=0;
                sensor_data800(app.Spectmask)=0;
                sensor_data900(app.Spectmask)=0;
                if app.converttoGSCheckBox.Value
                    sensor_data750 = sensor_data800;
                    sensor_data900 = sensor_data800;
                end
            catch
            end
            if app.SpectralExtractionButton.Value
                app.datap = cat(3,sensor_data750,sensor_data800,sensor_data900);
            end

            % show original sinogram and layout
            if app.Multispect
                rgbImagep = app.makeRGB(app.datap(:,:,1),app.datap(:,:,2),app.datap(:,:,3));
                imshow(rgbImagep,'Parent',app.UIAxes);
            else
                imshow(app.datap,[],'Parent',app.UIAxes);
            end
            imshow(imresize(app.layout,0.5),[],'Parent',app.UIAxes3);
            axis(app.UIAxes3,'on');
            axis(app.UIAxes3,'image');
            layoutsize=size(app.layout,2);
            xticks(app.UIAxes3,0:20:layoutsize);
            xticklabels(app.UIAxes3,1000*app.d*2*(0:20:layoutsize));
            xlabel(app.UIAxes3,'mm');
            yticks(app.UIAxes3,(0:20:layoutsize));
            yticklabels(app.UIAxes3,1000*app.d*2*(0:20:layoutsize));
            ylabel(app.UIAxes3,'mm');

            saveRGBsinogramRAW(app);
            POSSELSliderValueChanged(app);
            runrecon(app);
        end

        function TemporalEstimate(app,dataplotted)
            if app.TemporalMode == 2
                [~,Imin]=min(dataplotted);
                [~,Imax]=max(dataplotted);
                tpp = 1+Imin-Imax;
                scatter(app.UIAxes8,app.replayframe,1000*tpp*1580*app.dt,'*c');
                return;
            end
            if app.ThresholdEditField.Value<1
                measurements = regionprops(dataplotted, 'Area');
                allAreas = [measurements.Area];
                app.DiameterdeltammEditField.Value = allAreas(1)*2*app.dt*1580*1000;
            end
        end

        function masked_dynamics(app)
            SetrefrencesystemButtonPushed(app);
            app.TemporalMode = 2;
            app.UIAxes2.YLim = [-0.5 0.5];
            title(app.UIAxes, 'Masked synogram');
            title(app.UIAxes2, 'Masked pressure(t)');
            app.playmaskeddynamicsButton.BackgroundColor = [1 0 0];
            for dummy=1:5
                app.playmaskeddynamicsButton.Text=int2str(6-dummy);
                N=size(app.replaydata,1);
                for n_=1:N % N is num of frames
                    app.replayframe = n_;
                    app.Diameter=app.diamlist(n_);
                    app.separation=app.RefSep+0.5*(app.RefDiam-app.diamlist(n_));

                    app.datap = app.VarMask .* squeeze(app.replaydata(n_,:,:,1)); % frames for each sensor

                    POSSELSliderValueChanged(app);
                    pause(1/app.FsSpinner.Value);
                end
            end
            app.TemporalMode = 1;
            app.UIAxes2.YLim = [0 1];
            title(app.UIAxes, 'Variance mask');
            title(app.UIAxes2, 'Variance mask of sensor')
            app.playmaskeddynamicsButton.Text='play masked dynamics';
            app.playmaskeddynamicsButton.BackgroundColor = [0 1 0];
            app.datap = squeeze(app.replaydata(n_,:,:,1));
            POSSELSliderValueChanged(app);
            app.playmaskeddynamicsButton.Value=0;
        end

        function TemporalCalc(app)
            app.VarMask=[];
            app.TemporalMode=1;
            app.TemporalprocessingshowingLamp.Color=[0 1 0];
            X=size(app.replaydata,2);
            for x_=1:X % X is num of sensors
                Xn = squeeze(app.replaydata(:,x_,:,1)); % frames for each sensor
                app.VarMask(x_,:)=mat2gray(var(Xn));
            end
            if app.ThresholdEditField.Value<1
                app.VarMask = imbinarize(app.VarMask,app.ThresholdEditField.Value);
            end
            imshow(app.VarMask,[],'Parent',app.UIAxes);
            title(app.UIAxes, 'Variance mask');
            title(app.UIAxes2, 'Variance mask of sensor')
            app.UIAxes2.YLim = [0 1];
            POSSELSliderValueChanged(app);
            if app.Multispect
                app.playmaskeddynamicsButton.Visible = 0;
            else
                app.playmaskeddynamicsButton.Visible = 1;
            end
            app.ThresholdEditField.Visible = 1;
            app.ThresholdEditFieldLabel.Visible = 1;

            app.DiameterdeltammEditField.Visible = 1;
            app.DiameterdeltammEditFieldLabel.Visible = 1;
        end


        function FourierAnalysis(app)
            sensvox=size(app.datap,1);
            for indxx=1:sensvox
                sig = app.datap(indxx,:,1);
                sigg = [sig,zeros(10000,1)'];
                FFTfull(indxx,:) = abs(fft(sigg));
            end
            sensoffset = round(0.5*sensvox*(1+app.pos2show));
            N = length(sigg);
            f_vec = (0:N-1)/(N*app.dt);
            try
                sig_fft = FFTfull(sensoffset,:);
            catch
                if app.pos2show>0
                    sig_fft = FFTfull(end,:);
                else
                    sig_fft = FFTfull(1,:);
                end
            end
            plot(app.UIAxes6,f_vec*1e-6,abs(sig_fft)/max(abs(sig_fft)),'LineWidth',2,'color','r');

            cutx = find(f_vec*1e-6>10,1);
            FFTfull(:,cutx:end)=[];
            FFTfull=imresize(FFTfull,size(app.datap,[1,2]));

            imshow(FFTfull,[],'Parent',app.UIAxes7);

            axis(app.UIAxes7,'on');
            axis(app.UIAxes7,'image');
            yticks(app.UIAxes7,0:20:(app.senslength/app.d));
            yticklabels(app.UIAxes7,1000*app.d*(0:20:(app.senslength/app.d)));
            ylabel(app.UIAxes7,'position [mm]');
            xticks(app.UIAxes7,round(linspace(0,size(app.datap,2),11)));
            xticklabels(app.UIAxes7,0:10);
            xlabel(app.UIAxes7,'Frequency [Mhz]');

        end

        function conflict(app)
            if app.AVseperationmmSlider.Value/1000<(app.maxdimmEditField.Value-app.RefDiam)*0.5
                app.RedwhenAVconflictLamp.Color=[1,0,0];
            else
                app.RedwhenAVconflictLamp.Color=[0,0,0];
            end
        end

        function dynamics(app)
            app.UIAxes9.Visible = 0;
            app.CALCULATEButton.Enable = 0;
            SetrefrencesystemButtonPushed(app);
            app.ReadytoreplayLamp.Color=[1,0,0];
            app.replaydata = [];
            N = app.samplesEditField.Value;

            f=waitbar(0,'calculating');

            for indx=1:N
                app.Diameter=app.diamlist(indx);
                app.separation=app.RefSep+0.5*(app.RefDiam-app.diamlist(indx));
                scatter(app.UIAxes8,indx,1000*app.Diameter,'m');
                % run kwave forawrd
                runsim(app);
                % save for replay
                app.replaydata(indx,:,:,:)=app.datap(:,:,:);
                Frames{indx} = getframe(app.UIFigure);
                try
                    waitbar(indx/N,f);
                catch
                end
            end
            diams = app.diamlist;
            mapped_data=shiftdim(app.replaydata,1);

            csVars.axScanStride = app.d *1e3;
            csVars.fs = 1/app.dt;
            csVars.binSize = 1;
            csVars.stabTime = 0;
            csVars.delay=0;
            save('replaydata.mat','mapped_data','csVars','diams',"-v7.3");

            writerObj = VideoWriter('out_pulse.avi');
            writerObj.FrameRate = app.FsSpinner.Value;
            open(writerObj);
            for i=1:length(Frames)
                writeVideo(writerObj, Frames{i}.cdata);
            end
            switch app.vesseldynamicsSwitch.Value
                case 'linear'
                    for i=1:length(Frames)-1
                        writeVideo(writerObj, Frames{length(Frames)-i}.cdata);
                    end
            end
            close(writerObj);

            try
                close(f);
            catch
            end
            app.ReadytoreplayLamp.Color=[0,1,0];
            app.CALCULATEButton.BackgroundColor=[0,1,0];
            app.CALCULATEButton.Enable = 1;
            startupFcn(app);
            POSSELSliderValueChanged(app);
            app.UIAxes9.Visible = 1;
        end


        function runrecon(app)
            if app.Multispect == 0
                modsynog = app.datap(:,:,1);
                app.d = str2double(app.dmKnob.Value);
                % sparsify
                liveSensPos=1:size(app.datap,1); % no sparse state
                if app.reconmode == 1
                    if app.numofsensors == 1
                        liveSensPos=round(size(app.datap,1)/2); %middle
                    else
                        liveSensPos=round(linspace(1,size(app.datap,1),app.numofsensors));
                    end
                    modsynog=zeros(size(app.datap,[1,2]));
                    modsynog(liveSensPos,:)=app.datap(liveSensPos,:,1);

                    % this was tested to see how it works with huge stride
                    % in spars mode
                    % runs on even num of sensors to quickly switch between
                    % methods
                    % if ~mod(app.numofsensors,2)
                    %     modsynog = app.datap(liveSensPos,:,1);
                    %     app.d=app.senslength/(app.numofsensors-1);
                    % end
                end
                % show sinogram after sparsifying
                imshow(imrotate(modsynog,0),[],'Parent',app.UIAxes5);
                axis(app.UIAxes5,'on');
                axis(app.UIAxes5,'image');
                % run kwave recon
                app.d = app.d * app.d_ratio;

                if app.TimeReversalCheckBox.Value %handle both sparse and non sparse
                    Ny = 1+round(app.senslength/app.d);
                    Nx = round(max(app.datat)*1580/app.d);  %Nx only matters for time recon! depth of recon selection
                    kgrid = kWaveGrid(Nx, app.d, Ny, app.d);
                    mediumm.sound_speed = 1580;
                    kgrid.t_array = makeTime(kgrid, mediumm.sound_speed,[],app.datat(end));
                    source.p0 = 0;
                    sensor.mask = zeros(Nx, Ny);
                    sensor.mask(1, :) = (sum(modsynog>0,2)>0)';
                    modsynog = app.datap(liveSensPos,:,1);
                    input_args = {'PMLInside', false, 'PMLSize', 20, 'PlotSim', false}; % smoothing has no affect !
                    sensor.time_reversal_boundary_data = flip(modsynog',2)';
                    reconres = kspaceFirstOrder2DG(kgrid, mediumm, source, sensor, input_args{:});
                    if app.reconmode == 1
                        app.d=app.senslength/(app.numofsensors-1);
                    end
                else
                    reconres = kspaceLineRecon(flip(modsynog',2), app.d, app.dt, 1580, 'PosCond', false, 'Plot', false,'Interp', '*linear');
                end

                app.d = app.d/app.d_ratio; % to reverse angles affect if used
                % show recon result
                x_axis = [0, length(app.datat) * app.dt * 1580];
                y_axis = [0, size(modsynog,1) * app.d];
                [~, scale, prefix] = scaleSI(max([x_axis(end), y_axis(end)]));
                plot_scale = max(reconres(:)); plot_scale_min=min(reconres(:));
                imagesc(app.UIAxes4,y_axis * scale, x_axis * scale, reconres, [plot_scale_min, plot_scale]);
                xlabel(app.UIAxes4,['Sensor Position [' prefix 'm]']);
                ylabel(app.UIAxes4,['Depth [' prefix 'm]']);
                axis(app.UIAxes4,'image');
                colormap(app.UIAxes4,gray);
                app.recon_res=reconres;
            else % app.Multispect = 1
                modsynog = app.datap;
                app.d = str2double(app.dmKnob.Value);
                % sparsify
                liveSensPos=1:size(app.datap,1); % no sparse state
                if app.reconmode == 1
                    if app.numofsensors == 1
                        liveSensPos=round(size(app.datap,1)/2); %middle
                    else
                        liveSensPos=round(linspace(1,size(app.datap,1),app.numofsensors));
                    end
                    modsynog=zeros(size(app.datap));
                    modsynog(liveSensPos,:,1)=app.datap(liveSensPos,:,1);
                    modsynog(liveSensPos,:,2)=app.datap(liveSensPos,:,2);
                    modsynog(liveSensPos,:,3)=app.datap(liveSensPos,:,3);
                end
                % show sinogram after sparsifying
                modsynogRGB = app.makeRGB(modsynog(:,:,1),modsynog(:,:,2),modsynog(:,:,3));
                imshow(imrotate(modsynogRGB,0),'Parent',app.UIAxes5);

                if app.TimeReversalCheckBox.Value %handle both sparse and non sparse
                    Ny = 1+round(app.senslength/app.d);
                    Nx = round(max(app.datat)*1580/app.d);  %Nx only matters for time recon! depth of recon selection
                    kgrid = kWaveGrid(Nx, app.d, Ny, app.d);
                    mediumm.sound_speed = 1580;
                    kgrid.t_array = makeTime(kgrid, mediumm.sound_speed,[],app.datat(end));
                    source.p0 = 0;
                    sensor.mask = zeros(Nx, Ny);
                    sensor.mask(1, :) = (sum(modsynog(:,:,1)>0,2)>0)';
                    input_args = {'PMLInside', false, 'PMLSize', 20, 'PlotSim', false}; % smoothing has no affect !

                    modsynog = app.datap(liveSensPos,:,1);
                    sensor.time_reversal_boundary_data = flip(modsynog',2)';
                    app.reconres1 = kspaceFirstOrder2DG(kgrid, mediumm, source, sensor, input_args{:});
                    modsynog = app.datap(liveSensPos,:,2);
                    sensor.time_reversal_boundary_data = flip(modsynog',2)';
                    app.reconres2 = kspaceFirstOrder2DG(kgrid, mediumm, source, sensor, input_args{:});
                    modsynog = app.datap(liveSensPos,:,3);
                    sensor.time_reversal_boundary_data = flip(modsynog',2)';
                    app.reconres3 = kspaceFirstOrder2DG(kgrid, mediumm, source, sensor, input_args{:});

                    if app.reconmode == 1
                        app.d=app.senslength/(app.numofsensors-1);
                    end
                else
                    % run kwave recon
                    app.reconres1 = kspaceLineRecon(flip(modsynog(:,:,1)',2), app.d, app.dt, 1580, 'PosCond', false, 'Plot', false,'Interp', '*linear');
                    app.reconres2 = kspaceLineRecon(flip(modsynog(:,:,2)',2), app.d, app.dt, 1580, 'PosCond', false, 'Plot', false,'Interp', '*linear');
                    app.reconres3 = kspaceLineRecon(flip(modsynog(:,:,3)',2), app.d, app.dt, 1580, 'PosCond', false, 'Plot', false,'Interp', '*linear');
                end

                reconresRGB = app.makeRGB(app.reconres1,app.reconres2,app.reconres3);

                % show recon result
                x_axis = [0, length(app.datat) * app.dt * 1580];
                y_axis = [0, size(modsynog,1) * app.d];
                [~, scale, prefix] = scaleSI(max([x_axis(end), y_axis(end)]));
                imagesc(app.UIAxes4,y_axis * scale, x_axis * scale, reconresRGB);
                xlabel(app.UIAxes4,['Sensor Position [' prefix 'm]']);
                ylabel(app.UIAxes4,['Depth [' prefix 'm]']);
                axis(app.UIAxes4,'image');
                app.recon_res=reconresRGB;
                app.estimateSat;
            end
            
        end

        function saveRGBsinogramRAW(app)
            if size(app.datap,3)>1
                if rem(size(app.datap,1),3)
                    app.datap(end-rem(size(app.datap,1),3)+1:end,:,:)=[];
                end
                resCs = zeros((size(app.datap,[1 2])));
                resCs(1:3:end,:) = app.datap(1:3:end,:,1);
                resCs(2:3:end,:) = app.datap(2:3:end,:,2);
                resCs(3:3:end,:) = app.datap(3:3:end,:,3);
                csVars.axScanStride = app.d*1e3;
                csVars.fs = 1/app.dt;
                csVars.binSize = 1;
                csVars.stabTime = 0;
                csVars.delay=0;
                save('out_Spectmat.mat','resCs','csVars');
            end
        end

        function RGBres = makeRGB(app,R,G,B,strech)
            if nargin < 5
                strech=0;
            end
            WL_power(1)=max(peak2peak(app.datap(:,:,1)'));
            WL_power(2)=max(peak2peak(app.datap(:,:,2)'));
            WL_power(3)=max(peak2peak(app.datap(:,:,3)'));
            WL_power = WL_power / max(WL_power);
            R = R./WL_power(1);
            G = G./WL_power(2);
            B = B./WL_power(3);
            if strech
                RGBres=cat(3,R,G,B);
                RGBres = decorrstretch(RGBres,'Mode','covariance');
                [R,G,B]=imsplit(RGBres);
            end
            Rmin=min(R(:)); Rmax=max(R(:));
            Gmin=min(G(:)); Gmax=max(G(:));
            Bmin=min(B(:)); Bmax=max(B(:));
            globalmin = min([Rmin Gmin Bmin]);
            globalmax = max([Rmax Gmax Bmax]);
            Rscaled = rescale(R,'InputMin',globalmin,'InputMax',globalmax);
            Gscaled = rescale(G,'InputMin',globalmin,'InputMax',globalmax);
            Bscaled = rescale(B,'InputMin',globalmin,'InputMax',globalmax);
            RGBres=cat(3,Rscaled,Gscaled,Bscaled);
        end

        function results = estimateSat(app)
            % mua [1/cm]
            B = 1; % Blood content
            W = 0.51; % Water content
            M = 0; % Melanin content
            F = 0; % Fat content
            mua_ox_WL1 = calc_mua(app.wavelength1Spinner.Value,1,B,W,F,M);
            mua_ox_WL2 = calc_mua(app.wavelength2Spinner.Value,1,B,W,F,M);
            mua_ox_WL3 = calc_mua(app.wavelength3Spinner.Value,1,B,W,F,M);
            mua_nox_WL1 = calc_mua(app.wavelength1Spinner.Value,0,B,W,F,M);
            mua_nox_WL2 = calc_mua(app.wavelength2Spinner.Value,0,B,W,F,M);
            mua_nox_WL3 = calc_mua(app.wavelength3Spinner.Value,0,B,W,F,M);

            switch app.SatSourceKnob.Value
                case 'H'%after mcmatlab
                    sensor_dataWL1 = app.layoutRAW(:,:,1);
                    sensor_dataWL2 = app.layoutRAW(:,:,2);
                    sensor_dataWL3 = app.layoutRAW(:,:,3);
                    L = bwlabel(bwareaopen(sensor_dataWL2>0.1,10));
                    ART_mask = (L==1);
                    VEIN_mask = (L>1);

                    Partery_lambda1 = max(sensor_dataWL1(ART_mask),[],"all");
                    Partery_lambda2 = max(sensor_dataWL2(ART_mask),[],"all");
                    Partery_lambda3 = max(sensor_dataWL3(ART_mask),[],"all");
                    Pvein_lambda1 = max(sensor_dataWL1(VEIN_mask),[],"all");
                    Pvein_lambda2 = max(sensor_dataWL2(VEIN_mask),[],"all");
                    Pvein_lambda3 = max(sensor_dataWL3(VEIN_mask),[],"all");

                case 'P' %after kwave

                    sensor_data750 = app.datap(:,:,1);
                    sensor_data800 = app.datap(:,:,2);
                    sensor_data900 = app.datap(:,:,3);
                    if app.ABSformaskCheckBox.Value
                        rgbImagep = app.makeRGB(abs(sensor_data750),abs(sensor_data800),abs(sensor_data900),1);
                    else
                        rgbImagep = app.makeRGB((sensor_data750),(sensor_data800),(sensor_data900),1);
                    end
                    YCBCR = rgb2ycbcr(rgbImagep);
                    Y = YCBCR(:,:,1);
                    CB = YCBCR(:,:,2);
                    CR = YCBCR(:,:,3);
                    TH=app.CRSlider.Value/255;
                    [z,x]=find(CR==max(CR,[],'all'));
                    CR(z-5:z+5,x-5:x+5)=TH;

                    %figure(4); imshow(CR,[]);
                    
                    ART_mask = CB>1.3*TH;
                    VEIN_mask = CR>1.3*TH;

                    ART_mask=bwareaopen(ART_mask,2);
                    VEIN_mask=bwareaopen(VEIN_mask,2);

                    ART_mask=bwareaopen(imdilate(ART_mask,ones(2)),10);
                    VEIN_mask=bwareaopen(imdilate(VEIN_mask,ones(2)),10);

                    VEIN_mask(end/2-50:end/2+50,:)=0;
                    startA=find(max(ART_mask),1); ART_mask(:,startA+10:end)=0;
                    startV=find(max(VEIN_mask),1); VEIN_mask(:,startV+20:end)=0;

                    sensor_dataWL1 = double(sensor_data750);
                    sensor_dataWL2 = double(sensor_data800);
                    sensor_dataWL3 = double(sensor_data900);

                    if (app.AO2Spinner.Value-app.LVO2Spinner.Value)<20
                        ART_mask=ART_mask*0; VEIN_mask=VEIN_mask*0;
                        ART_mask(215:220,175:185)=1;
                        VEIN_mask(155:160,200:210)=1; VEIN_mask(280:285,200:210)=1;
                        VEIN_mask=VEIN_mask>0; ART_mask=ART_mask>0;
                    end

                    Partery_lambda1 = mean(sensor_dataWL1(ART_mask),'all');
                    Partery_lambda2 = mean(sensor_dataWL2(ART_mask),'all');
                    Partery_lambda3 = mean(sensor_dataWL3(ART_mask),'all');
                    Pvein_lambda1 = mean(sensor_dataWL1(VEIN_mask),'all');
                    Pvein_lambda2 = mean(sensor_dataWL2(VEIN_mask),'all');
                    Pvein_lambda3 = mean(sensor_dataWL3(VEIN_mask),'all');

                case 'Hrecon' %after recon
                    YCBCR = rgb2ycbcr(app.makeRGB(app.reconres1,app.reconres2,app.reconres3,1));
                    Y = YCBCR(:,:,1);
                    CB = YCBCR(:,:,2);
                    CR = YCBCR(:,:,3);
                    %figure(5); imshow(CR,[]);
                    TH=app.CRSlider.Value/255;
                    ART_mask = CR<TH*0.7;
                    VEIN_mask = CR>TH*1.1;

                    ART_mask(VEIN_mask)=0;
                    VEIN_mask(ART_mask)=0;

                    sensor_dataWL1 = double(app.reconres1);
                    sensor_dataWL2 = double(app.reconres2);
                    sensor_dataWL3 = double(app.reconres3);

                    Partery_lambda1 = max(sensor_dataWL1(ART_mask),[],'all');
                    Partery_lambda2 = max(sensor_dataWL2(ART_mask),[],'all');
                    Partery_lambda3 = max(sensor_dataWL3(ART_mask),[],'all');
                    Pvein_lambda1 = max(sensor_dataWL1(VEIN_mask),[],'all');
                    Pvein_lambda2 = max(sensor_dataWL2(VEIN_mask),[],'all');
                    Pvein_lambda3 = max(sensor_dataWL3(VEIN_mask),[],'all');

                    if (app.AO2Spinner.Value-app.LVO2Spinner.Value)<20
                        ART_mask=ART_mask*0; VEIN_mask=VEIN_mask*0;
                        ART_mask(180:190,215:225)=1;
                        VEIN_mask(203:215,155:165)=1; VEIN_mask(203:215,275:285)=1;
                        VEIN_mask=VEIN_mask>0; ART_mask=ART_mask>0;

                        Partery_lambda1 = mean(sensor_dataWL1(ART_mask),'all');
                        Partery_lambda2 = mean(sensor_dataWL2(ART_mask),'all');
                        Partery_lambda3 = mean(sensor_dataWL3(ART_mask),'all');
                        Pvein_lambda1 = mean(sensor_dataWL1(VEIN_mask),'all');
                        Pvein_lambda2 = mean(sensor_dataWL2(VEIN_mask),'all');
                        Pvein_lambda3 = mean(sensor_dataWL3(VEIN_mask),'all');
                    end
            end

            try
                close(5);
            catch
            end
            %figure(5);
            %nexttile; imshow(sensor_dataWL1.*ART_mask,[]); title('art');
            %nexttile; imshow(sensor_dataWL1.*VEIN_mask,[]); title('vein');

            if 0 %slopes
                sensor_dataWL1(~ART_mask)=0; P_slope_ART_WL1=max(sensor_dataWL1);
                sensor_dataWL2(~ART_mask)=0; P_slope_ART_WL2=max(sensor_dataWL2);
                sensor_dataWL3(~ART_mask)=0; P_slope_ART_WL3=max(sensor_dataWL3);
                sensor_dataWL1 = app.layoutRAW(:,:,1);
                sensor_dataWL2 = app.layoutRAW(:,:,2);
                sensor_dataWL3 = app.layoutRAW(:,:,3);
                sensor_dataWL1(~VEIN_mask)=0; P_slope_VEIN_WL1=max(sensor_dataWL1);
                sensor_dataWL2(~VEIN_mask)=0; P_slope_VEIN_WL2=max(sensor_dataWL2);
                sensor_dataWL3(~VEIN_mask)=0; P_slope_VEIN_WL3=max(sensor_dataWL3);

                P_slope_ART_WL1(P_slope_ART_WL1==0)=[];
                %P_slope_ART_WL1 = P_slope_ART_WL1 - min(P_slope_ART_WL1);
                %P_slope_ART_WL1 = P_slope_ART_WL1 / max(P_slope_ART_WL1);

                P_slope_ART_WL2(P_slope_ART_WL2==0)=[];
                %P_slope_ART_WL2 = P_slope_ART_WL2 - min(P_slope_ART_WL2);
                %P_slope_ART_WL2 = P_slope_ART_WL2 / max(P_slope_ART_WL2);

                P_slope_ART_WL3(P_slope_ART_WL3==0)=[];
                %P_slope_ART_WL3 = P_slope_ART_WL3 - min(P_slope_ART_WL3);
                %P_slope_ART_WL3 = P_slope_ART_WL3 / max(P_slope_ART_WL3);

                P_slope_VEIN_WL1(P_slope_VEIN_WL1==0)=[];
                %P_slope_VEIN_WL1 = P_slope_VEIN_WL1 - min(P_slope_VEIN_WL1);
                %P_slope_VEIN_WL1 = P_slope_VEIN_WL1 / max(P_slope_VEIN_WL1);

                P_slope_VEIN_WL2(P_slope_VEIN_WL2==0)=[];
                %P_slope_VEIN_WL2 = P_slope_VEIN_WL2 - min(P_slope_VEIN_WL2);
                %P_slope_VEIN_WL2 = P_slope_VEIN_WL2 / max(P_slope_VEIN_WL2);

                P_slope_VEIN_WL3(P_slope_VEIN_WL3==0)=[];
                %P_slope_VEIN_WL3 = P_slope_VEIN_WL3 - min(P_slope_VEIN_WL3);
                %P_slope_VEIN_WL3 = P_slope_VEIN_WL3 / max(P_slope_VEIN_WL3);

                %figure; plot(P_slope_ART_WL1); hold on;
                %plot(P_slope_ART_WL2); plot(P_slope_ART_WL3);
                %plot(P_slope_VEIN_WL1); plot(P_slope_VEIN_WL2); plot(P_slope_VEIN_WL3);
            end

            % old and wrong approach
            % syms sat_art sat_vein
            % Go = 1; %0.124+1*0.05328;
            % Gno = 1; %0.124;
            % eq1 = Partery_lambda1 * 6.4841   == sat_art  * Go * mua_ox_WL1 + (1 - sat_art)  * Gno * mua_nox_WL1;
            % eq2 = Pvein_lambda1  * 10.2267    == sat_vein * Go * mua_ox_WL1 + (1 - sat_vein) * Gno * mua_nox_WL1;
            % eq3 = Partery_lambda2 * 6.8124  == sat_art  * Go * mua_ox_WL2 + (1 - sat_art)  * Gno * mua_nox_WL2;
            % eq4 = Pvein_lambda2  * 9.2118     == sat_vein * Go * mua_ox_WL2 + (1 - sat_vein) * Gno * mua_nox_WL2;
            % eq5 = Partery_lambda3 * 7.3992    == sat_art  * Go * mua_ox_WL3 + (1 - sat_art)  * Gno * mua_nox_WL3;
            % eq6 = Pvein_lambda3 * 9.7534     == sat_vein * Go * mua_ox_WL3 + (1 - sat_vein) * Gno * mua_nox_WL3;
            % eqns = [eq1, eq2, eq3, eq4, eq5, eq6];
            % vars = [sat_art, sat_vein];
            % [A,b] = equationsToMatrix(eqns,vars);
            % vars = lsqminnorm(double(A),double(b));

            try
            correctscale(1)=app.AO2Spinner.Value/100; correctscale(2)=app.LVO2Spinner.Value/100;
            correctscale(3)=Partery_lambda1/((app.AO2Spinner.Value/100)*mua_ox_WL1+(1-(app.AO2Spinner.Value/100))*mua_nox_WL1);
            correctscale(4)=Pvein_lambda1/((app.LVO2Spinner.Value/100)*mua_ox_WL1+(1-(app.LVO2Spinner.Value/100))*mua_nox_WL1);
            correctscale(5)=Partery_lambda2/((app.AO2Spinner.Value/100)*mua_ox_WL2+(1-(app.AO2Spinner.Value/100))*mua_nox_WL2);
            correctscale(6)=Pvein_lambda2/((app.LVO2Spinner.Value/100)*mua_ox_WL2+(1-(app.LVO2Spinner.Value/100))*mua_nox_WL2);
            correctscale(7)=Partery_lambda3/((app.AO2Spinner.Value/100)*mua_ox_WL3+(1-(app.AO2Spinner.Value/100))*mua_nox_WL3);
            correctscale(8)=Pvein_lambda3/((app.LVO2Spinner.Value/100)*mua_ox_WL3+(1-(app.LVO2Spinner.Value/100))*mua_nox_WL3);
            catch
                warning('Sat estimation: either the veins mask or the artery mask is null');
            end

            PA1=Partery_lambda1; PV1=Pvein_lambda1;
            PA2=Partery_lambda2; PV2=Pvein_lambda2;
            PA3=Partery_lambda3; PV3=Pvein_lambda3;
            HbO1 = mua_ox_WL1; HbO2 = mua_ox_WL2; HbO3 = mua_ox_WL3; 
            Hb1 = mua_nox_WL1; Hb2 = mua_nox_WL2; Hb3 = mua_nox_WL3;
            f = @(x)mysystem(x,PA1,PV1,PA2,PV2,PA3,PV3,HbO1,HbO2,HbO3,Hb1,Hb2,Hb3);
            c = @(x)mycon(x); % does nothing
            sol0 = [0.9 0.1 0.1 0.1 0.1 0.1 0.1 0.1];
            opt = optimoptions('lsqnonlin') ;
            opt.Algorithm = 'interior-point';
            opt.Display = "iter-detailed";
            %opt.PlotFcn = 'optimplotfval';
            opt.TypicalX = [0.95 0.75 0.1 0.1 0.1 0.1 0.1 0.1];
            switch app.SatSourceKnob.Value
                case 'H'
                    LB = [0 0 1 1 1 1 1 1]*0.05;
                    UB = [1 1 0 0 0 0 0 0]+[0 0 1 1 1 1 1 1]*1.6;
                case 'P'
                    LB = [0 0 1 1 1 1 1 1]*0.01;
                    UB = [1 1 0 0 0 0 0 0]+[0 0 1 1 1 1 1 1]*0.03;
                case 'Hrecon'
                    LB = [0 0 1 1 1 1 1 1]*0;
                    UB = [1 1 0 0 0 0 0 0]+[0 0 1 1 1 1 1 1]*1.3;
            end
            if sum(correctscale>UB)||sum(correctscale<LB)
                sound(sin(1:500));
            end
            A = [-1 1 0 0 0 0 0 0];
            vars = lsqnonlin(f,sol0,LB,UB,A,0,0*A,0,c,opt);
            varsmean = mean(vars(3:end));
            LB = [0 0 1 1 1 1 1 1]*varsmean*0.5;
            UB = [1 1 0 0 0 0 0 0]+[0 0 1 1 1 1 1 1]*varsmean*1.5;
            if sum(correctscale>UB)||sum(correctscale<LB)
                sound(sin(1:500));
            end
            vars = lsqnonlin(f,sol0,LB,UB,A,0,0*A,0,c,opt);

            app.ARTSATEditField.Value=min(100,100*vars(1));
            app.VEINSATEditField.Value=max(0,100*vars(2));
            switch app.SatSourceKnob.Value
                case 'H'
                    app.ARTSATEditField.Value=min(100,100*vars(1)*2.29-108.3);
                    app.VEINSATEditField.Value=max(0,100*vars(2)*2-55.232);
                case 'P'
                    app.ARTSATEditField.Value=min(100,100*vars(1)*2.04-87);
                    app.VEINSATEditField.Value=max(0,100*vars(2)*1.81-41.8);
                case 'Hrecon'
                    app.ARTSATEditField.Value=min(100,100*vars(1)*2.3-108.3);
                    app.VEINSATEditField.Value=max(0,100*vars(2)*2-50);
            end
            display(vars);
            display(correctscale);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd))
            app.CenterPanel.AutoResizeChildren = 'off';
            drawnow;
            pause(2);
            app.CenterPanel.AutoResizeChildren = 'on';

            app.d = str2double(app.dmKnob.Value);
            app.t_end = app.TfinalusSlider.Value*1e-6;
            app.Dist = app.sensdistmmSlider.Value/1000;
            app.senslength = app.scanwidthmmSlider.Value/1000;
            app.pos2show = app.POSSELSlider.Value;
            app.VLint = app.LveinintSlider.Value;
            app.VRint = app.RveinintSlider.Value;
            app.VRdiam = app.RveindiammmSlider.Value/1000;
            app.VLdiam = app.LveindiammmSlider.Value/1000;
            app.Diameter = app.ArterydiametermmSlider.Value/1000;
            app.angle = app.AVangleSlider.Value;
            app.separation = app.AVseperationmmSlider.Value/1000;
            app.reconmode = 0;
            app.numofsensors = app.numofsensorsSpinner.Value;
            app.MaxFreqHzEditField.Value = 1580 / (2*app.d);
            app.medium = app.BiocorrectacousticheterogeneityofmediumCheckBox.Value;
            app.hydrogel = app.HydrogeloversensorCheckBox.Value;
            app.attenComp = app.attenCompifpossibleCheckBox.Value;
            app.TemporalMode = 0;

            value = app.HeatingfunctionKnob.Value;
            switch value
                case "Uniform"
                    app.Toastflag = 0;
                case "Toast++"
                    app.Toastflag = 1;
                case "MCMATLAB"
                    app.Toastflag = 2;
            end
            MethodKnobValueChanged(app);
            SetrefrencesystemButtonPushed(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {905, 905, 905};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {905, 905};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {467, '1x', 454};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: AVseperationmmSlider
        function AVseperationmmSliderValueChanged(app, event)
            app.separation = app.AVseperationmmSlider.Value/1000;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: AVangleSlider
        function AVangleSliderValueChanged(app, event)
            app.angle = app.AVangleSlider.Value;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: ArterydiametermmSlider
        function ArterydiametermmSliderValueChanged(app, event)
            app.Diameter = app.ArterydiametermmSlider.Value/1000;
            SetrefrencesystemButtonPushed(app);
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: LveindiammmSlider
        function LveindiammmSliderValueChanged(app, event)
            app.VLdiam = app.LveindiammmSlider.Value/1000;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: RveindiammmSlider
        function RveindiammmSliderValueChanged(app, event)
            app.VRdiam = app.RveindiammmSlider.Value/1000;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: RveinintSlider
        function RveinintSliderValueChanged(app, event)
            app.VRint = app.RveinintSlider.Value;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: LveinintSlider
        function LveinintSliderValueChanged(app, event)
            app.VLint = app.LveinintSlider.Value;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: scanwidthmmSlider
        function scanwidthmmSliderValueChanged(app, event)
            app.senslength = app.scanwidthmmSlider.Value/1000;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: sensdistmmSlider
        function sensdistmmSliderValueChanged(app, event)
            app.Dist = app.sensdistmmSlider.Value/1000;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: TfinalusSlider
        function TfinalusSliderValueChanged(app, event)
            app.t_end = app.TfinalusSlider.Value*1e-6;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: dmKnob
        function dmKnobValueChanged(app, event)
            app.d = str2double(app.dmKnob.Value);
            app.MaxFreqHzEditField.Value = 1580 / (2*app.d);
            RUNSIMButtonValueChanged(app);
        end

        % Value changing function: POSSELSlider
        function POSSELSliderValueChanging(app, event)

            if app.TemporalMode
                return
            end

            changingValue = event.Value;
            app.pos2show = -changingValue;

            sensvox=size(app.datap,1);
            sensoffset = 0.5*sensvox*app.pos2show;
            passed=1;
            try
                plot(app.UIAxes2,1000000*app.datat,app.datap(round(sensoffset+0.5*sensvox),:,1),'r');
                passed = 0;
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,1000000*app.datat,app.datap(round(sensoffset+0.5*sensvox),:,2),'g');
                plot(app.UIAxes2,1000000*app.datat,app.datap(round(sensoffset+0.5*sensvox),:,3),'b');
                hold(app.UIAxes2,'off');
            catch
                hold(app.UIAxes2,'off');
                if passed*changingValue>0
                    plot(app.UIAxes2,1000000*app.datat,app.datap(1,:,1));
                elseif passed*changingValue<0
                    plot(app.UIAxes2,1000000*app.datat,app.datap(end,:,1));
                end
            end

            if app.Multispect
                rgbImagep = app.makeRGB(app.datap(:,:,1),app.datap(:,:,2),app.datap(:,:,3));
                imshow(rgbImagep,'Parent',app.UIAxes);
            else
                imshow(app.datap,[],'Parent',app.UIAxes);
            end
            hold(app.UIAxes,'on');
            yline(app.UIAxes,round(sensoffset+0.5*sensvox),'c');
            hold(app.UIAxes,'off');

            axis(app.UIAxes,'on');
            axis(app.UIAxes,'image');
            yticks(app.UIAxes,0:20:(app.senslength/app.d));
            yticklabels(app.UIAxes,1000*app.d*(0:20:(app.senslength/app.d)));
            ylabel(app.UIAxes,'position [mm]');
            xticks(app.UIAxes,(0:50:length(app.datat)));
            xticklabels(app.UIAxes,1000000*(0:50*app.dt:app.t_end));
            xlabel(app.UIAxes,'time [us]');

        end

        % Value changed function: POSSELSlider
        function POSSELSliderValueChanged(app, event)
            value = app.POSSELSlider.Value;
            app.pos2show = -value;
            sensvox=size(app.datap,1);
            sensoffset = 0.5*sensvox*app.pos2show;

            if app.CALCULATEButton.Enable == 1 % after dynamics
                try
                    Xn = squeeze(app.replaydata(:,round(sensoffset+0.5*sensvox),:,:));
                catch
                    if value>0
                        Xn = squeeze(app.replaydata(:,1,:,:));
                    else
                        Xn = squeeze(app.replaydata(:,end,:,:));
                    end
                end
                switch app.vesseldynamicsSwitch.Value
                    case 'linear'
                        Xn = [Xn;flip(Xn)];
                        Xn = repmat(Xn,25,1);
                    case 'real'
                        Xn = repmat(Xn,25,1);
                end
                maxframes = round(0.35*size(Xn,2));
                if size(Xn,1)>maxframes
                    Xn(maxframes:end,:,:)=[];
                end
                if app.Multispect
                    Xn = app.makeRGB(Xn(:,:,1),Xn(:,:,2),Xn(:,:,3));
                else
                    Xn = cat(3,mat2gray(Xn),mat2gray(Xn),mat2gray(Xn));
                end

                % Envelope exctraction
                A = mat2gray(stdfilt(imnlmfilt(Xn(:,:,2)),true(5)));
                QQ = im2bw(A,0.25);
                QQs = bwskel(QQ,'MinBranchLength',100);
                QQt = bwareaopen(QQs,100);
                L = labelmatrix(bwconncomp(QQt));
                props = regionprops(L,'Extent','EulerNumber');
                for indx=find([props.Extent]>0.3)
                    L(L==indx)=0;
                end
                for indx=find([props.EulerNumber]<1)
                    L(L==indx)=0;
                end
                L=im2bw(L,0);
                LL=L'; %backup
                L = imdilate(L,strel("disk",1));
                RR=Xn(:,:,1); GG=Xn(:,:,2); BB=Xn(:,:,3);
                RR(L)=1; GG(L)=0; BB(L)=0;
                Xn=cat(3,RR,GG,BB);

                % PLOT diameter
                if (sum(LL(:))>0) && (app.playmaskeddynamicsButton.BackgroundColor(1)<1)
                    COM = round(sum(sum(LL,2)'.*(1:size(LL,1)))/sum(LL(:)));
                    Lup = LL; Lup(COM:end,:)=0;
                    Ldown = LL; Ldown(1:COM,:)=0;
                    if max(labelmatrix(bwconncomp(LL)),[],"all")>1
                        % both sides
                        for indx=find(sum(Lup,1)>1)
                            reduces = find(Lup(:,indx));
                            Lup(reduces,indx)=0;
                            Lup(reduces(1),indx)=1;
                        end
                        for indx=find(sum(Ldown,1)>1)
                            reduces = find(Ldown(:,indx));
                            Ldown(reduces,indx)=0;
                            Ldown(reduces(1),indx)=1;
                        end
                        [yyu,xxu] = find(Lup==1);
                        [yyd,xxd] = find(Ldown==1);
                        X=intersect(xxu,xxd);
                        yyu(not(ismember(xxu,X)))=[];
                        yyd(not(ismember(xxd,X)))=[];
                        diff=yyd-yyu;
                        figure(55); plot(X/app.FsSpinner.Value,diff*app.dt*1580*1000); ylim([0,5]);
                        ylabel('diameter [mm]'); xlabel('t [s]')
                    else
                        % just front
                        for indx=find(sum(LL,1)>1)
                            reduces = find(LL(:,indx));
                            LL(reduces,indx)=0; LL(reduces(1),indx)=1;
                        end
                        [yyu,xxu] = find(LL==1);
                        figure(55); plot(xxu/app.FsSpinner.Value,(-yyu+max(yyu)+4*peak2peak(yyu))*app.dt*1580*1000); ylim([0,5]); pause(0.1);
                        ylabel('diameter [mm]'); xlabel('t [s]')
                    end
                end

                imshow(Xn,[],'Parent',app.UIAxes9);
                axis(app.UIAxes9,'on');
                axis(app.UIAxes9,'image');
                yticks(app.UIAxes9,'auto');
                ylabel(app.UIAxes9,'frame');
                xticks(app.UIAxes9,(0:50:length(app.datat)));
                xticklabels(app.UIAxes9,1000000*(0:50*app.dt:app.t_end));
                xlabel(app.UIAxes9,'time [us]');
            end

            if app.TemporalMode == 1 % show var mask
                try
                    plot(app.UIAxes2,1000000*app.datat,app.VarMask(round(sensoffset+0.5*sensvox),:));
                    dataplotted = app.VarMask(round(sensoffset+0.5*sensvox),:);
                catch
                    if value>0
                        plot(app.UIAxes2,1000000*app.datat,app.VarMask(1,:));
                        dataplotted = app.VarMask(1,:);
                    else
                        plot(app.UIAxes2,1000000*app.datat,app.VarMask(end,:));
                        dataplotted = app.VarMask(end,:);
                    end
                end
                imshow(app.VarMask,[],'Parent',app.UIAxes);
                hold(app.UIAxes,'on');
                yline(app.UIAxes,round(sensoffset+0.5*sensvox),'c');

                if app.OverlayextremalinesCheckBox.Value
                    a = app.mindimmEditField.Value*0.5;
                    dd = app.Dist-a;
                    x=-app.senslength*0.5:app.d:app.senslength*0.5;
                    Af = -a + sqrt(x.^2+dd^2+2*dd*a+a^2);
                    Ab = Af + 2*a;
                    plot(app.UIAxes,Af*3.2917/app.d,1:length(x),'y','LineWidth',1);
                    plot(app.UIAxes,Ab*3.2917/app.d,1:length(x),'y--','LineWidth',1);

                    a = app.maxdimmEditField.Value*0.5;
                    dd = app.Dist-a;
                    x=-app.senslength*0.5:app.d:app.senslength*0.5;
                    Af = -a + sqrt(x.^2+dd^2+2*dd*a+a^2);
                    Ab = Af + 2*a;
                    plot(app.UIAxes,Af*3.2917/app.d,1:length(x),'y','LineWidth',1);
                    plot(app.UIAxes,Ab*3.2917/app.d,1:length(x),'y--','LineWidth',1);
                end

                TemporalEstimate(app,dataplotted);
            else % regular mode, no mask + dynamic mode
                passed=1;
                try
                    plot(app.UIAxes2,1000000*app.datat,app.datap(round(sensoffset+0.5*sensvox),:,1),'r');
                    passed=0;
                    dataplotted=app.datap(round(sensoffset+0.5*sensvox),:,1);
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,1000000*app.datat,app.datap(round(sensoffset+0.5*sensvox),:,2),'g');
                    plot(app.UIAxes2,1000000*app.datat,app.datap(round(sensoffset+0.5*sensvox),:,3),'b');
                    hold(app.UIAxes2,'off');
                catch
                    hold(app.UIAxes2,'off');
                    if passed*value>0
                        plot(app.UIAxes2,1000000*app.datat,app.datap(1,:,1));
                        dataplotted=app.datap(1,:,1);
                    elseif passed*value<0
                        plot(app.UIAxes2,1000000*app.datat,app.datap(end,:,1));
                        dataplotted=app.datap(end,:,1);
                    end
                end

                if app.TemporalMode == 2 % dynamic mode
                    imshow(app.datap,[-0.3 0.5],'Parent',app.UIAxes);
                    TemporalEstimate(app,dataplotted);
                else
                    if app.Multispect
                        rgbImagep = app.makeRGB(app.datap(:,:,1),app.datap(:,:,2),app.datap(:,:,3));
                        imshow(rgbImagep,'Parent',app.UIAxes);
                    else
                        imshow(app.datap,[],'Parent',app.UIAxes);
                    end
                end
                hold(app.UIAxes,'on');
                yline(app.UIAxes,round(sensoffset+0.5*sensvox),'c');


                %overlay
                if app.OverlayextremalinesCheckBox.Value
                    a = app.Diameter*0.5;
                    b = app.angle;
                    cl = app.VLdiam*0.5;
                    cr = app.VRdiam*0.5;
                    dd = app.Dist-a;

                    f = app.separation;
                    if cl>cr
                        ffl=f;
                        ffr=f+(cl-cr);
                    else
                        ffr=f;
                        ffl=f+(cr-cl);
                    end
                    x=-app.senslength*0.5:app.d:app.senslength*0.5;
                    Af = -a + sqrt(x.^2+dd^2+2*dd*a+a^2);
                    Ab = Af + 2*a;

                    VLf = -cl + sqrt((x-(a+ffl+cl)*sind(b)).^2+(dd+a-cl+(a+ffl+cl)*cosd(b))^2+2*cl*(dd+a-cl+(a+ffl+cl)*cosd(b))+cl^2);
                    VLb = VLf + 2*cl;

                    VRf = -cr + sqrt((x+(a+ffr+cr)*sind(b)).^2+(dd+a-cr+(a+ffr+cr)*cosd(b))^2+2*cr*(dd+a-cr+(a+ffr+cr)*cosd(b))+cr^2);
                    VRb = VRf + 2*cr;

                    plot(app.UIAxes,Af*3.2917/app.d,1:length(x),'y','LineWidth',0.6);
                    plot(app.UIAxes,Ab*3.2917/app.d,1:length(x),'y--','LineWidth',0.6);
                    plot(app.UIAxes,VLf*3.2917/app.d,1:length(x),'r','LineWidth',0.6);
                    plot(app.UIAxes,VLb*3.2917/app.d,1:length(x),'r--','LineWidth',0.6);
                    plot(app.UIAxes,VRf*3.2917/app.d,1:length(x),'g','LineWidth',0.6);
                    plot(app.UIAxes,VRb*3.2917/app.d,1:length(x),'g--','LineWidth',0.6);
                end
            end

            hold(app.UIAxes,'off');

            axis(app.UIAxes,'on');
            axis(app.UIAxes,'image');
            xlim(app.UIAxes,[0 length(app.datat)]);
            yticks(app.UIAxes,0:20:(app.senslength/app.d));
            yticklabels(app.UIAxes,1000*app.d*(0:20:(app.senslength/app.d)));
            ylabel(app.UIAxes,'position [mm]');
            xticks(app.UIAxes,(0:50:length(app.datat)));
            xticklabels(app.UIAxes,1000000*(0:50*app.dt:app.t_end));
            xlabel(app.UIAxes,'time [us]');

            if app.TemporalMode == 0
              %  FourierAnalysis(app);
              % takes a long time on 1e-5 dx no need for now
            end
        end

        % Value changed function: numofsensorsSpinner
        function numofsensorsSpinnerValueChanged(app, event)
            app.numofsensors = app.numofsensorsSpinner.Value;
            if app.reconmode
                runrecon(app);
            end
        end

        % Value changed function: reconmodeSwitch
        function reconmodeSwitchValueChanged(app, event)
            value = app.reconmodeSwitch.Value;
            switch value
                case "spars"
                    app.reconmode = 1;
                case "full"
                    app.reconmode = 0;
            end
            runrecon(app);
        end

        % Value changed function: RUNSIMButton
        function RUNSIMButtonValueChanged(app, event)
            if app.RUNSIMButton.Value
                app.runsim();
            end
        end

        % Menu selected function: setlikearteryMenu
        function setlikearteryMenuSelected(app, event)
            app.RveindiammmSlider.Value=app.ArterydiametermmSlider.Value;
            RveindiammmSliderValueChanged(app)
        end

        % Menu selected function: setlikeRMenu
        function setlikeRMenuSelected(app, event)
            app.LveindiammmSlider.Value=app.RveindiammmSlider.Value;
            LveindiammmSliderValueChanged(app)
        end

        % Menu selected function: setlikeRMenu_2
        function setlikeRMenu_2Selected(app, event)
            app.LveinintSlider.Value=app.RveinintSlider.Value;
            LveinintSliderValueChanged(app)
        end

        % Button pushed function: SetrefrencesystemButton
        function SetrefrencesystemButtonPushed(app, event)
            app.RefDiam=app.ArterydiametermmSlider.Value/1000;
            app.RefSep=app.AVseperationmmSlider.Value/1000;
            app.maxdimmEditField.Value=app.RefDiam*app.maxcompressionSpinner.Value/100;
            app.mindimmEditField.Value=app.RefDiam*app.mincompressionSpinner.Value/100;
            app.samplesEditField.Value=ceil((60/app.BPMSpinner.Value)*app.FsSpinner.Value);

            cla(app.UIAxes8);
            yline(app.UIAxes8,1000*app.mindimmEditField.Value,'g');
            hold(app.UIAxes8,'on');
            yline(app.UIAxes8,1000*app.maxdimmEditField.Value,'r');
            xlim(app.UIAxes8, [1 app.samplesEditField.Value]);
            yline(app.UIAxes8,1000*app.Diameter,'k');

            N = app.samplesEditField.Value;
            switch app.vesseldynamicsSwitch.Value
                case 'linear'
                    app.diamlist = linspace(app.mindimmEditField.Value,app.maxdimmEditField.Value,N);
                case 'real'
                    load('realPulse.mat');
                    Pulseshape = smoothdata([ones(100,1);Pulseshape;ones(100,1)],'movmean',200);
                    Pulseshape = Pulseshape - 1;
                    delta = app.maxdimmEditField.Value - app.mindimmEditField.Value;
                    Pulseshape = Pulseshape * delta + app.mindimmEditField.Value;
                    app.diamlist = imresize(Pulseshape,[N,1]);
            end
            plot(app.UIAxes8,1000*app.diamlist,'b');

            conflict(app);
        end

        % Value changed function: mincompressionSpinner
        function mincompressionSpinnerValueChanged(app, event)
            SetrefrencesystemButtonPushed(app);
        end

        % Value changed function: maxcompressionSpinner
        function maxcompressionSpinnerValueChanged(app, event)
            SetrefrencesystemButtonPushed(app);
        end

        % Value changed function: BPMSpinner
        function BPMSpinnerValueChanged(app, event)
            SetrefrencesystemButtonPushed(app);
        end

        % Value changed function: FsSpinner
        function FsSpinnerValueChanged(app, event)
            SetrefrencesystemButtonPushed(app);
        end

        % Value changed function: RUNPULSEButton
        function RUNPULSEButtonValueChanged(app, event)
            if app.RUNPULSEButton.Value
                app.RUNPULSEButton.Value = 0;
            end
            app.RUNSIMButton.Value = 1;
            dynamics(app);
            app.RUNSIMButton.Value = 0;
        end

        % Button pushed function: REPLAYButton
        function REPLAYButtonPushed(app, event)
            implay('out_pulse.avi',app.FsSpinner.Value);
        end

        % Value changed function: 
        % BiocorrectacousticheterogeneityofmediumCheckBox
        function BiocorrectacousticheterogeneityofmediumCheckBoxValueChanged(app, event)
            app.medium = app.BiocorrectacousticheterogeneityofmediumCheckBox.Value;
            if app.medium == 0
                app.hydrogel=0;
                app.HydrogeloversensorCheckBox.Value=0;
            end
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: OverlayextremalinesCheckBox
        function OverlayextremalinesCheckBoxValueChanged(app, event)
            try
                POSSELSliderValueChanged(app);
            catch
            end
        end

        % Value changed function: HydrogeloversensorCheckBox
        function HydrogeloversensorCheckBoxValueChanged(app, event)
            app.hydrogel = app.HydrogeloversensorCheckBox.Value;
            if app.HydrogeloversensorCheckBox.Value
                if app.BiocorrectacousticheterogeneityofmediumCheckBox.Value<1
                    app.BiocorrectacousticheterogeneityofmediumCheckBox.Value=1;
                    BiocorrectacousticheterogeneityofmediumCheckBoxValueChanged(app);
                    return;
                end
            end
            RUNSIMButtonValueChanged(app);
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            TemporalCalc(app);
        end

        % Value changed function: AddNoise16dBSNRCheckBox
        function AddNoise16dBSNRCheckBoxValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: attenCompifpossibleCheckBox
        function attenCompifpossibleCheckBoxValueChanged(app, event)
            app.attenComp = app.attenCompifpossibleCheckBox.Value;
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: playmaskeddynamicsButton
        function playmaskeddynamicsButtonValueChanged(app, event)
            masked_dynamics(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            if app.TemporalMode == 1
                TemporalCalc(app);
            else
                if app.ThresholdEditField.Value<1
                    app.VarMask = imbinarize(app.VarMask,app.ThresholdEditField.Value);
                end
            end
        end

        % Value changed function: vesseldynamicsSwitch
        function vesseldynamicsSwitchValueChanged(app, event)
            app.SetrefrencesystemButtonPushed(app);
        end

        % Value changed function: HeatingfunctionKnob
        function HeatingfunctionKnobValueChanged(app, event)
            value = app.HeatingfunctionKnob.Value;
            switch value
                case "Uniform"
                    app.Toastflag = 0;
                    app.MethodKnob.Value='Temporal';
                    app.SpectralExtractionButton.Value=0;
                    MethodKnobValueChanged(app);
                case "Toast++"
                    app.Toastflag = 1;
                    app.MethodKnob.Value='Temporal';
                    app.SpectralExtractionButton.Value=0;
                    MethodKnobValueChanged(app);
                case "MCMATLAB"
                    app.Toastflag = 2;
            end
            RUNSIMButtonValueChanged(app);

        end

        % Value changed function: wavelength1Spinner
        function wavelength1SpinnerValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: wavelength2Spinner
        function wavelength2SpinnerValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: wavelength3Spinner
        function wavelength3SpinnerValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: AO2Spinner
        function AO2SpinnerValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: LVO2Spinner
        function LVO2SpinnerValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: RVO2Spinner
        function RVO2SpinnerValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: MethodKnob
        function MethodKnobValueChanged(app, event)
            value = app.MethodKnob.Value;
            switch value
                case "Temporal"
                    app.CALCULATEButton.Visible = 1;
                    app.wavelength1Spinner.Visible = 0;
                    app.wavelength1SpinnerLabel.Visible = 0;
                    app.wavelength3Spinner.Visible = 0;
                    app.wavelength3SpinnerLabel.Visible = 0;
                    app.Multispect = 0;
                    app.SpectralExtractionButton.Value=0;
                    app.SpectralExtractionButton.Visible = 0;
                    app.CRSlider.Visible = 0;
                    app.CRSliderLabel.Visible = 0;
                    app.ABSformaskCheckBox.Visible = 0;
                    app.converttoGSCheckBox.Visible = 0;

                    if app.UIAxes9.Visible
                        msgbox('Run pulse again for validity');
                    end
                case "Spectral"
                    app.CALCULATEButton.Visible = 0;
                    app.wavelength1Spinner.Visible = 1;
                    app.wavelength1SpinnerLabel.Visible = 1;
                    app.wavelength3Spinner.Visible = 1;
                    app.wavelength3SpinnerLabel.Visible = 1;
                    app.Multispect = 1;
                    app.SpectralExtractionButton.Visible = 1;
                    app.CRSlider.Visible = 1;
                    app.CRSliderLabel.Visible = 1;
                    app.ABSformaskCheckBox.Visible = 1;
                    app.converttoGSCheckBox.Visible = 1;
                    app.HeatingfunctionKnob.Value = 'MCMATLAB';
                    HeatingfunctionKnobValueChanged(app);
                    if app.UIAxes9.Visible
                        msgbox('Run pulse again for validity');
                    end
                case "Temporal + Spectral"
                    app.CALCULATEButton.Visible = 1;
                    app.wavelength1Spinner.Visible = 1;
                    app.wavelength1SpinnerLabel.Visible = 1;
                    app.wavelength3Spinner.Visible = 1;
                    app.wavelength3SpinnerLabel.Visible = 1;
                    app.Multispect = 1;
                    app.SpectralExtractionButton.Visible = 1;
                    app.SpectralExtractionButton.Value = 1;
                    app.CRSlider.Visible = 1;
                    app.CRSliderLabel.Visible = 1;
                    app.ABSformaskCheckBox.Visible = 1;
                    app.converttoGSCheckBox.Visible = 1;
                    app.converttoGSCheckBox.Value = 1;
                    app.HeatingfunctionKnob.Value = 'MCMATLAB';
                    HeatingfunctionKnobValueChanged(app);
                    if app.UIAxes9.Visible
                        msgbox('Run pulse again for validity');
                    end
                otherwise
                    msgbox('Not available')
                    app.MethodKnob.Value = event.PreviousValue;
            end
        end

        % Value changed function: AddabsorbersnoiseCheckBox
        function AddabsorbersnoiseCheckBoxValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: SpectralExtractionButton
        function SpectralExtractionButtonValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: alphadegSpinner
        function alphadegSpinnerValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: optmodelengthumSpinner
        function optmodelengthumSpinnerValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: multisensmodeSwitch
        function multisensmodeSwitchValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: COMPENSCheckBox
        function COMPENSCheckBoxValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: ABSformaskCheckBox
        function ABSformaskCheckBoxValueChanged(app, event)
            if app.SpectralExtractionButton.Value
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: converttoGSCheckBox
        function converttoGSCheckBoxValueChanged(app, event)
            if app.SpectralExtractionButton.Value
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: CRSlider
        function CRSliderValueChanged(app, event)
            if app.SpectralExtractionButton.Value
                RUNSIMButtonValueChanged(app);
            end
            app.estimateSat;
        end

        % Menu selected function: RUNMenu
        function RUNMenuSelected(app, event)
            estimateSat(app)
        end

        % Value changed function: SatSourceKnob
        function SatSourceKnobValueChanged(app, event)
            try
            estimateSat(app)
            catch
            end
        end

        % Value changed function: TimeReversalCheckBox
        function TimeReversalCheckBoxValueChanged(app, event)
            runrecon(app);
        end

        % Value changed function: IluminationFormHDropDown
        function IluminationFormHDropDownValueChanged(app, event)
            if app.Toastflag == 2
                RUNSIMButtonValueChanged(app);
            end
        end

        % Value changed function: LaserNoiseCheckBox
        function LaserNoiseCheckBoxValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Value changed function: ElipticalarteryCheckBox
        function ElipticalarteryCheckBoxValueChanged(app, event)
            RUNSIMButtonValueChanged(app);
        end

        % Button pushed function: saverunButton
        function saverunButtonPushed(app, event)
            diams = app.ArterydiametermmSlider.Value;
            data = app.datap;
            csVars.axScanStride = app.d *1e3;
            csVars.fs = 1/app.dt;
            csVars.binSize = 1;
            csVars.stabTime = 0;
            csVars.delay=0;
            %csVars.new_separation = app.separation;
            %csVars.new_VRdiam = app.VRdiam;

            mode.length=app.optmodelengthumSpinner.Value;
            mode.theta=app.alphadegSpinner.Value;

            finalname = convertStringsToChars(append(string(datetime),' currentrun.mat'));
            finalname = strrep(finalname,':','-');

            save(finalname,'data','csVars','diams','mode',"-v7.3");
        end

        % Button pushed function: TMPButton
        function TMPButtonPushed(app, event)
            app.RUNSIMButton.Value=1;
            app.VLdiam = 0.1e-3;
            app.VLint = 0;

            app.Diameter = 2.5e-3;
            app.VRint = 0;
            app.VRdiam = 0.1e-3;
            app.separation = 0e-3;
            runsim(app); data = app.datap;
            finalname = convertStringsToChars(append(string(datetime),' wide_C00.mat'));
            finalname = strrep(finalname,':','-'); save(finalname,'data',"-v7.3");

            app.Diameter = 0.1e-3;
            app.VRint = 1;
            app.VRdiam = 2.5e-3;
            for indx=0:7
                app.separation = indx*1e-3;
                runsim(app); data = app.datap;
                finalname = convertStringsToChars(append(string(datetime),sprintf(' wide_C%d.mat',indx)));
                finalname = strrep(finalname,':','-'); save(finalname,'data',"-v7.3");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.LeftPanel);
            title(app.UIAxes3, 'layout')
            app.AVseperationmmSlider = uislider(app.LeftPanel);
            app.AVseperationmmSlider.Limits = [0 10];
            app.AVseperationmmSlider.ValueChangedFcn = createCallbackFcn(app, @AVseperationmmSliderValueChanged, true);
            app.AVseperationmmSlider.MinorTicks = [];
            app.AVseperationmmSlider.Value = 0.5;
            app.AVseperationmmSliderLabel = uilabel(app.LeftPanel);
            app.AVseperationmmSliderLabel.Text = 'A-V seperation [mm]';
            app.AVangleLabel = uilabel(app.LeftPanel);
            app.AVangleLabel.Text = 'A-V angle';
            app.AVangleSlider = uislider(app.LeftPanel);
            app.AVangleSlider.Limits = [25 165];
            app.AVangleSlider.ValueChangedFcn = createCallbackFcn(app, @AVangleSliderValueChanged, true);
            app.AVangleSlider.MinorTicks = [];
            app.AVangleSlider.Value = 88;
            app.ArterydiametermmSliderLabel = uilabel(app.LeftPanel);
            app.ArterydiametermmSliderLabel.Text = 'Artery diameter [mm]';
            app.ArterydiametermmSlider = uislider(app.LeftPanel);
            app.ArterydiametermmSlider.Limits = [0.2 4];
            app.ArterydiametermmSlider.ValueChangedFcn = createCallbackFcn(app, @ArterydiametermmSliderValueChanged, true);
            app.ArterydiametermmSlider.Value = 2.5;
            app.RveindiammmSliderLabel = uilabel(app.LeftPanel);
            app.RveindiammmSliderLabel.Text = 'R vein diam [mm]';
            app.RveindiammmSlider = uislider(app.LeftPanel);
            app.RveindiammmSlider.Limits = [0.1 4];
            app.RveindiammmSlider.ValueChangedFcn = createCallbackFcn(app, @RveindiammmSliderValueChanged, true);
            app.RveindiammmSlider.Value = 0.2;
            app.LveindiammmSliderLabel = uilabel(app.LeftPanel);
            app.LveindiammmSliderLabel.Text = 'L vein diam [mm]';
            app.LveindiammmSlider = uislider(app.LeftPanel);
            app.LveindiammmSlider.Limits = [0.1 4];
            app.LveindiammmSlider.ValueChangedFcn = createCallbackFcn(app, @LveindiammmSliderValueChanged, true);
            app.LveindiammmSlider.Value = 0.2;
            app.LveinintSliderLabel = uilabel(app.LeftPanel);
            app.LveinintSliderLabel.Text = 'L vein int %';
            app.LveinintSlider = uislider(app.LeftPanel);
            app.LveinintSlider.Limits = [0 1];
            app.LveinintSlider.ValueChangedFcn = createCallbackFcn(app, @LveinintSliderValueChanged, true);
            app.RveinintSliderLabel = uilabel(app.LeftPanel);
            app.RveinintSliderLabel.Text = 'R vein int %';
            app.RveinintSlider = uislider(app.LeftPanel);
            app.RveinintSlider.Limits = [0 1];
            app.RveinintSlider.ValueChangedFcn = createCallbackFcn(app, @RveinintSliderValueChanged, true);
            app.scanwidthmmSliderLabel = uilabel(app.LeftPanel);
            app.scanwidthmmSliderLabel.Text = 'scan width[mm]';
            app.scanwidthmmSlider = uislider(app.LeftPanel);
            app.scanwidthmmSlider.Limits = [1 25];
            app.scanwidthmmSlider.ValueChangedFcn = createCallbackFcn(app, @scanwidthmmSliderValueChanged, true);
            app.scanwidthmmSlider.Value = 20;
            app.sensdistmmSliderLabel = uilabel(app.LeftPanel);
            app.sensdistmmSliderLabel.Text = 'sens dist [mm]';
            app.sensdistmmSlider = uislider(app.LeftPanel);
            app.sensdistmmSlider.Limits = [2 10];
            app.sensdistmmSlider.ValueChangedFcn = createCallbackFcn(app, @sensdistmmSliderValueChanged, true);
            app.sensdistmmSlider.Value = 4;
            app.TfinalusSliderLabel = uilabel(app.LeftPanel);
            app.TfinalusSliderLabel.Text = 'T final [us]';
            app.TfinalusSlider = uislider(app.LeftPanel);
            app.TfinalusSlider.Limits = [4 15];
            app.TfinalusSlider.ValueChangedFcn = createCallbackFcn(app, @TfinalusSliderValueChanged, true);
            app.TfinalusSlider.Value = 10;
            app.dmKnobLabel = uilabel(app.LeftPanel);
            app.dmKnobLabel.Text = 'd [m]';
            app.dmKnob = uiknob(app.LeftPanel, 'discrete');
            app.dmKnob.Items = {'5e-6', '1e-5', '3e-5', '5e-5', '1e-4', '2e-4'};
            app.dmKnob.ValueChangedFcn = createCallbackFcn(app, @dmKnobValueChanged, true);
            app.dmKnob.Value = '1e-5';
            app.RUNSIMButton = uibutton(app.LeftPanel, 'state');
            app.RUNSIMButton.ValueChangedFcn = createCallbackFcn(app, @RUNSIMButtonValueChanged, true);
            app.RUNSIMButton.Text = 'RUN SIM';
            app.BPMSpinnerLabel = uilabel(app.LeftPanel);
            app.BPMSpinnerLabel.Text = 'BPM';
            app.BPMSpinner = uispinner(app.LeftPanel);
            app.BPMSpinner.Limits = [20 120];
            app.BPMSpinner.ValueChangedFcn = createCallbackFcn(app, @BPMSpinnerValueChanged, true);
            app.BPMSpinner.Value = 120;
            app.FsSpinnerLabel = uilabel(app.LeftPanel);
            app.FsSpinnerLabel.Text = 'Fs';
            app.FsSpinner = uispinner(app.LeftPanel);
            app.FsSpinner.Limits = [10 100];
            app.FsSpinner.ValueChangedFcn = createCallbackFcn(app, @FsSpinnerValueChanged, true);
            app.FsSpinner.Value = 10;
            app.mincompressionSpinnerLabel = uilabel(app.LeftPanel);
            app.mincompressionSpinnerLabel.Text = 'min compression %';
            app.mincompressionSpinner = uispinner(app.LeftPanel);
            app.mincompressionSpinner.Limits = [1 99];
            app.mincompressionSpinner.ValueChangedFcn = createCallbackFcn(app, @mincompressionSpinnerValueChanged, true);
            app.mincompressionSpinner.Value = 30;
            app.maxcompressionSpinnerLabel = uilabel(app.LeftPanel);
            app.maxcompressionSpinnerLabel.Text = 'max compression %';
            app.maxcompressionSpinner = uispinner(app.LeftPanel);
            app.maxcompressionSpinner.Limits = [100 200];
            app.maxcompressionSpinner.ValueChangedFcn = createCallbackFcn(app, @maxcompressionSpinnerValueChanged, true);
            app.maxcompressionSpinner.Value = 125;
            app.SetrefrencesystemButton = uibutton(app.LeftPanel, 'push');
            app.SetrefrencesystemButton.ButtonPushedFcn = createCallbackFcn(app, @SetrefrencesystemButtonPushed, true);
            app.SetrefrencesystemButton.Visible = 'off';
            app.SetrefrencesystemButton.Text = 'Set refrence system';
            app.maxdimmEditFieldLabel = uilabel(app.LeftPanel);
            app.maxdimmEditFieldLabel.Text = 'max dim [m]';
            app.maxdimmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maxdimmEditField.Editable = 'off';
            app.mindimmEditFieldLabel = uilabel(app.LeftPanel);
            app.mindimmEditFieldLabel.Text = 'min dim [m]';
            app.mindimmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mindimmEditField.Editable = 'off';
            app.RedwhenAVconflictLampLabel = uilabel(app.LeftPanel);
            app.RedwhenAVconflictLampLabel.Text = 'Red when A-V conflict';
            app.RedwhenAVconflictLamp = uilamp(app.LeftPanel);
            app.samplesEditFieldLabel = uilabel(app.LeftPanel);
            app.samplesEditFieldLabel.Text = '# samples';
            app.samplesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.samplesEditField.Editable = 'off';
            app.RUNPULSEButton = uibutton(app.LeftPanel, 'state');
            app.RUNPULSEButton.ValueChangedFcn = createCallbackFcn(app, @RUNPULSEButtonValueChanged, true);
            app.RUNPULSEButton.Text = 'RUN PULSE';
            app.TemporalConfigLabel = uilabel(app.LeftPanel);
            app.TemporalConfigLabel.Text = 'Temporal Config';
            app.MaxFreqHzEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxFreqHzEditFieldLabel.Text = 'Max Freq [Hz]';
            app.MaxFreqHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxFreqHzEditField.Editable = 'off';
            app.OverlayextremalinesCheckBox = uicheckbox(app.LeftPanel);
            app.OverlayextremalinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayextremalinesCheckBoxValueChanged, true);
            app.OverlayextremalinesCheckBox.Text = 'Overlay extrema lines';
            app.vesseldynamicsSwitchLabel = uilabel(app.LeftPanel);
            app.vesseldynamicsSwitchLabel.Text = 'vessel dynamics';
            app.vesseldynamicsSwitch = uiswitch(app.LeftPanel, 'slider');
            app.vesseldynamicsSwitch.Items = {'linear', 'real'};
            app.vesseldynamicsSwitch.ValueChangedFcn = createCallbackFcn(app, @vesseldynamicsSwitchValueChanged, true);
            app.vesseldynamicsSwitch.Value = 'linear';
            app.ReadytoreplayLampLabel = uilabel(app.LeftPanel);
            app.ReadytoreplayLampLabel.Text = 'Ready to replay';
            app.ReadytoreplayLamp = uilamp(app.LeftPanel);
            app.REPLAYButton = uibutton(app.LeftPanel, 'push');
            app.REPLAYButton.ButtonPushedFcn = createCallbackFcn(app, @REPLAYButtonPushed, true);
            app.REPLAYButton.Text = 'REPLAY';
            app.BiocorrectacousticheterogeneityofmediumCheckBox = uicheckbox(app.LeftPanel);
            app.BiocorrectacousticheterogeneityofmediumCheckBox.ValueChangedFcn = createCallbackFcn(app, @BiocorrectacousticheterogeneityofmediumCheckBoxValueChanged, true);
            app.BiocorrectacousticheterogeneityofmediumCheckBox.Text = 'Bio correct acoustic heterogeneity of medium';
            app.HydrogeloversensorCheckBox = uicheckbox(app.LeftPanel);
            app.HydrogeloversensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @HydrogeloversensorCheckBoxValueChanged, true);
            app.HydrogeloversensorCheckBox.Text = 'Hydrogel over sensor';
            app.AddNoise16dBSNRCheckBox = uicheckbox(app.LeftPanel);
            app.AddNoise16dBSNRCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddNoise16dBSNRCheckBoxValueChanged, true);
            app.AddNoise16dBSNRCheckBox.Text = 'Add Noise (16dB SNR)';
            app.attenCompifpossibleCheckBox = uicheckbox(app.LeftPanel);
            app.attenCompifpossibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @attenCompifpossibleCheckBoxValueChanged, true);
            app.attenCompifpossibleCheckBox.Text = 'attenComp (if possible)';
            app.HeatingfunctionKnobLabel = uilabel(app.LeftPanel);
            app.HeatingfunctionKnobLabel.Text = 'Heating function';
            app.HeatingfunctionKnob = uiknob(app.LeftPanel, 'discrete');
            app.HeatingfunctionKnob.Items = {'Uniform', 'Toast++', 'MCMATLAB'};
            app.HeatingfunctionKnob.ValueChangedFcn = createCallbackFcn(app, @HeatingfunctionKnobValueChanged, true);
            app.HeatingfunctionKnob.Value = 'MCMATLAB';
            app.AddabsorbersnoiseCheckBox = uicheckbox(app.LeftPanel);
            app.AddabsorbersnoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddabsorbersnoiseCheckBoxValueChanged, true);
            app.AddabsorbersnoiseCheckBox.Text = 'Add absorbers noise';
            app.alphadegSpinnerLabel = uilabel(app.LeftPanel);
            app.alphadegSpinnerLabel.Text = 'alpha [deg]';
            app.alphadegSpinner = uispinner(app.LeftPanel);
            app.alphadegSpinner.Limits = [0 45];
            app.alphadegSpinner.RoundFractionalValues = 'on';
            app.alphadegSpinner.ValueChangedFcn = createCallbackFcn(app, @alphadegSpinnerValueChanged, true);
            app.optmodelengthumSpinnerLabel = uilabel(app.LeftPanel);
            app.optmodelengthumSpinnerLabel.Text = 'opt mode length [um]';
            app.optmodelengthumSpinner = uispinner(app.LeftPanel);
            app.optmodelengthumSpinner.Limits = [0 5000];
            app.optmodelengthumSpinner.ValueChangedFcn = createCallbackFcn(app, @optmodelengthumSpinnerValueChanged, true);
            app.multisensmodeSwitchLabel = uilabel(app.LeftPanel);
            app.multisensmodeSwitchLabel.Visible = 'off';
            app.multisensmodeSwitchLabel.Text = 'multi sens mode';
            app.multisensmodeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.multisensmodeSwitch.Items = {'array', 'scan'};
            app.multisensmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @multisensmodeSwitchValueChanged, true);
            app.multisensmodeSwitch.Visible = 'off';
            app.multisensmodeSwitch.Value = 'scan';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'X (1).PNG';
            app.COMPENSCheckBox = uicheckbox(app.LeftPanel);
            app.COMPENSCheckBox.ValueChangedFcn = createCallbackFcn(app, @COMPENSCheckBoxValueChanged, true);
            app.COMPENSCheckBox.Text = 'COMPENS.';
            app.IluminationFormHDropDownLabel = uilabel(app.LeftPanel);
            app.IluminationFormHDropDownLabel.Text = 'Ilumination Form (H)';
            app.IluminationFormHDropDown = uidropdown(app.LeftPanel);
            app.IluminationFormHDropDown.Items = {'2_Lines_PAR', '2_Lines_SQR', 'BOX_1CM_1CM', 'BOX_1CM_2CM', 'DISC_1.25CM', 'ARMADILLO'};
            app.IluminationFormHDropDown.ValueChangedFcn = createCallbackFcn(app, @IluminationFormHDropDownValueChanged, true);
            app.IluminationFormHDropDown.Value = 'ARMADILLO';
            app.LaserNoiseCheckBox = uicheckbox(app.LeftPanel);
            app.LaserNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaserNoiseCheckBoxValueChanged, true);
            app.LaserNoiseCheckBox.Text = 'Laser Noise';
            app.ElipticalarteryCheckBox = uicheckbox(app.LeftPanel);
            app.ElipticalarteryCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElipticalarteryCheckBoxValueChanged, true);
            app.ElipticalarteryCheckBox.Text = 'Eliptical artery';
            app.saverunButton = uibutton(app.LeftPanel, 'push');
            app.saverunButton.ButtonPushedFcn = createCallbackFcn(app, @saverunButtonPushed, true);
            app.saverunButton.Text = 'save run';
            app.TMPButton = uibutton(app.LeftPanel, 'push');
            app.TMPButton.ButtonPushedFcn = createCallbackFcn(app, @TMPButtonPushed, true);
            app.TMPButton.Text = 'TMP';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes7 = uiaxes(app.CenterPanel);
            title(app.UIAxes7, '2D FFT')
            xlabel(app.UIAxes7, 'Frequency [MHz]')
            ylabel(app.UIAxes7, 'position [mm]')
            app.UIAxes6 = uiaxes(app.CenterPanel);
            title(app.UIAxes6, 'Selected POS FFT')
            xlabel(app.UIAxes6, 'Frequency [MHz]')
            ylabel(app.UIAxes6, 'Power')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XLim = [0 10];
            app.UIAxes6.YLim = [0 1];
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'pressure (t)')
            xlabel(app.UIAxes2, 'Time [us]')
            ylabel(app.UIAxes2, 'pressure')
            app.UIAxes2.YLim = [-0.6 0.6];
            app.UIAxes2.Tag = '2';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'sinogram')
            xlabel(app.UIAxes, 'Time [us]')
            ylabel(app.UIAxes, 'position [mm]')
            app.UIAxes.Tag = '1';
            app.UIAxes8 = uiaxes(app.CenterPanel);
            title(app.UIAxes8, 'Artery motion')
            xlabel(app.UIAxes8, 'Frame')
            ylabel(app.UIAxes8, 'Diameter [mm]')
            app.UIAxes8.XLim = [0 6];
            app.UIAxes8.YLim = [0 4];
            app.UIAxes9 = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes9, 'depth')
            ylabel(app.UIAxes9, 'frames')
            app.UIAxes9.Visible = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.CenterPanel);
            app.ThresholdEditFieldLabel.Visible = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Visible = 'off';
            app.ThresholdEditField.Value = 1;
            app.DiameterdeltammEditFieldLabel = uilabel(app.CenterPanel);
            app.DiameterdeltammEditFieldLabel.Visible = 'off';
            app.DiameterdeltammEditFieldLabel.Text = 'Diameter delta [mm]';
            app.DiameterdeltammEditField = uieditfield(app.CenterPanel, 'numeric');
            app.DiameterdeltammEditField.Editable = 'off';
            app.DiameterdeltammEditField.Visible = 'off';
            app.TemporalprocessingshowingLampLabel = uilabel(app.CenterPanel);
            app.TemporalprocessingshowingLampLabel.Text = 'Temporal processing showing';
            app.TemporalprocessingshowingLamp = uilamp(app.CenterPanel);
            app.POSSELSlider = uislider(app.CenterPanel);
            app.POSSELSlider.Limits = [-1 1];
            app.POSSELSlider.ValueChangedFcn = createCallbackFcn(app, @POSSELSliderValueChanged, true);
            app.POSSELSlider.ValueChangingFcn = createCallbackFcn(app, @POSSELSliderValueChanging, true);
            app.POSSELLabel = uilabel(app.CenterPanel);
            app.POSSELLabel.Text = 'POS SELL';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Reconstruction')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'sparsed sinogram')
            app.numofsensorsSpinnerLabel = uilabel(app.RightPanel);
            app.numofsensorsSpinnerLabel.Text = 'num of sensors';
            app.numofsensorsSpinner = uispinner(app.RightPanel);
            app.numofsensorsSpinner.Limits = [1 200];
            app.numofsensorsSpinner.ValueChangedFcn = createCallbackFcn(app, @numofsensorsSpinnerValueChanged, true);
            app.numofsensorsSpinner.Value = 20;
            app.reconmodeSwitchLabel = uilabel(app.RightPanel);
            app.reconmodeSwitchLabel.Text = 'recon mode';
            app.reconmodeSwitch = uiswitch(app.RightPanel, 'slider');
            app.reconmodeSwitch.Items = {'spars', 'full'};
            app.reconmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @reconmodeSwitchValueChanged, true);
            app.reconmodeSwitch.Value = 'full';
            app.ReconstractionLabel = uilabel(app.RightPanel);
            app.ReconstractionLabel.Text = 'Reconstraction';
            app.AO2SpinnerLabel = uilabel(app.RightPanel);
            app.AO2SpinnerLabel.Text = 'A O2';
            app.AO2Spinner = uispinner(app.RightPanel);
            app.AO2Spinner.Limits = [0 100];
            app.AO2Spinner.ValueChangedFcn = createCallbackFcn(app, @AO2SpinnerValueChanged, true);
            app.AO2Spinner.Value = 98;
            app.LVO2SpinnerLabel = uilabel(app.RightPanel);
            app.LVO2SpinnerLabel.Text = 'LV O2';
            app.LVO2Spinner = uispinner(app.RightPanel);
            app.LVO2Spinner.Limits = [0 100];
            app.LVO2Spinner.ValueChangedFcn = createCallbackFcn(app, @LVO2SpinnerValueChanged, true);
            app.LVO2Spinner.Value = 75;
            app.RVO2SpinnerLabel = uilabel(app.RightPanel);
            app.RVO2SpinnerLabel.Text = 'RV O2';
            app.RVO2Spinner = uispinner(app.RightPanel);
            app.RVO2Spinner.Limits = [0 100];
            app.RVO2Spinner.ValueChangedFcn = createCallbackFcn(app, @RVO2SpinnerValueChanged, true);
            app.RVO2Spinner.Value = 75;
            app.wavelength1SpinnerLabel = uilabel(app.RightPanel);
            app.wavelength1SpinnerLabel.Text = 'wavelength 1';
            app.wavelength1Spinner = uispinner(app.RightPanel);
            app.wavelength1Spinner.Limits = [500 1000];
            app.wavelength1Spinner.ValueChangedFcn = createCallbackFcn(app, @wavelength1SpinnerValueChanged, true);
            app.wavelength1Spinner.Value = 680;
            app.wavelength2SpinnerLabel = uilabel(app.RightPanel);
            app.wavelength2SpinnerLabel.Text = 'wavelength 2';
            app.wavelength2Spinner = uispinner(app.RightPanel);
            app.wavelength2Spinner.Limits = [500 1000];
            app.wavelength2Spinner.ValueChangedFcn = createCallbackFcn(app, @wavelength2SpinnerValueChanged, true);
            app.wavelength2Spinner.Value = 800;
            app.wavelength3SpinnerLabel = uilabel(app.RightPanel);
            app.wavelength3SpinnerLabel.Text = 'wavelength 3';
            app.wavelength3Spinner = uispinner(app.RightPanel);
            app.wavelength3Spinner.Limits = [500 1000];
            app.wavelength3Spinner.ValueChangedFcn = createCallbackFcn(app, @wavelength3SpinnerValueChanged, true);
            app.wavelength3Spinner.Value = 900;
            app.MethodKnobLabel = uilabel(app.RightPanel);
            app.MethodKnobLabel.Text = 'Method';
            app.MethodKnob = uiknob(app.RightPanel, 'discrete');
            app.MethodKnob.Items = {'Model', 'Temporal', 'Temporal + Spectral', 'Spectral'};
            app.MethodKnob.ValueChangedFcn = createCallbackFcn(app, @MethodKnobValueChanged, true);
            app.MethodKnob.Value = 'Temporal';
            app.CALCULATEButton = uibutton(app.RightPanel, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Enable = 'off';
            app.CALCULATEButton.Text = 'CALCULATE';
            app.playmaskeddynamicsButton = uibutton(app.RightPanel, 'state');
            app.playmaskeddynamicsButton.ValueChangedFcn = createCallbackFcn(app, @playmaskeddynamicsButtonValueChanged, true);
            app.playmaskeddynamicsButton.Visible = 'off';
            app.playmaskeddynamicsButton.Text = 'play masked dynamics';
            app.SpectralConfigLabel_2 = uilabel(app.RightPanel);
            app.SpectralConfigLabel_2.Text = 'Spectral Config';
            app.SpectralExtractionButton = uibutton(app.RightPanel, 'state');
            app.SpectralExtractionButton.ValueChangedFcn = createCallbackFcn(app, @SpectralExtractionButtonValueChanged, true);
            app.SpectralExtractionButton.Visible = 'off';
            app.SpectralExtractionButton.Text = 'Spectral Extraction';
            app.ABSformaskCheckBox = uicheckbox(app.RightPanel);
            app.ABSformaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ABSformaskCheckBoxValueChanged, true);
            app.ABSformaskCheckBox.Text = 'ABS for mask';
            app.ABSformaskCheckBox.Value = true;
            app.converttoGSCheckBox = uicheckbox(app.RightPanel);
            app.converttoGSCheckBox.ValueChangedFcn = createCallbackFcn(app, @converttoGSCheckBoxValueChanged, true);
            app.converttoGSCheckBox.Text = 'convert to GS';
            app.CRSliderLabel = uilabel(app.RightPanel);
            app.CRSliderLabel.Text = 'CR';
            app.CRSlider = uislider(app.RightPanel);
            app.CRSlider.Limits = [16 240];
            app.CRSlider.ValueChangedFcn = createCallbackFcn(app, @CRSliderValueChanged, true);
            app.CRSlider.Value = 128;
            app.OPOLabel = uilabel(app.RightPanel);
            app.OPOLabel.Text = '680-940 OPO';
            app.EstimationsLabel = uilabel(app.RightPanel);
            app.EstimationsLabel.Text = 'Estimations';
            app.ARTSATEditFieldLabel = uilabel(app.RightPanel);
            app.ARTSATEditFieldLabel.Text = 'ART SAT';
            app.ARTSATEditField = uieditfield(app.RightPanel, 'numeric');
            app.ARTSATEditField.Editable = 'off';
            app.VEINSATEditFieldLabel = uilabel(app.RightPanel);
            app.VEINSATEditFieldLabel.Text = 'VEIN SAT';
            app.VEINSATEditField = uieditfield(app.RightPanel, 'numeric');
            app.VEINSATEditField.Editable = 'off';
            app.SatSourceKnobLabel = uilabel(app.RightPanel);
            app.SatSourceKnobLabel.Text = 'SatSource';
            app.SatSourceKnob = uiknob(app.RightPanel, 'discrete');
            app.SatSourceKnob.Items = {'H', 'P', 'Hrecon'};
            app.SatSourceKnob.ValueChangedFcn = createCallbackFcn(app, @SatSourceKnobValueChanged, true);
            app.SatSourceKnob.Value = 'H';
            app.TimeReversalCheckBox = uicheckbox(app.RightPanel);
            app.TimeReversalCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeReversalCheckBoxValueChanged, true);
            app.TimeReversalCheckBox.Text = 'TimeReversal';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.setlikearteryMenu = uimenu(app.ContextMenu);
            app.setlikearteryMenu.MenuSelectedFcn = createCallbackFcn(app, @setlikearteryMenuSelected, true);
            app.setlikearteryMenu.Text = 'set like artery';
            % Assign app.ContextMenu
            app.RveindiammmSlider.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.setlikeRMenu = uimenu(app.ContextMenu2);
            app.setlikeRMenu.MenuSelectedFcn = createCallbackFcn(app, @setlikeRMenuSelected, true);
            app.setlikeRMenu.Text = 'set like R';
            % Assign app.ContextMenu2
            app.LveindiammmSlider.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.setlikeRMenu_2 = uimenu(app.ContextMenu3);
            app.setlikeRMenu_2.MenuSelectedFcn = createCallbackFcn(app, @setlikeRMenu_2Selected, true);
            app.setlikeRMenu_2.Text = 'set like R';
            % Assign app.ContextMenu3
            app.LveinintSlider.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.RUNMenu = uimenu(app.ContextMenu4);
            app.RUNMenu.MenuSelectedFcn = createCallbackFcn(app, @RUNMenuSelected, true);
            app.RUNMenu.Text = 'RUN';
            % Assign app.ContextMenu4
            app.EstimationsLabel.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kwavehand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end